classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Menu         matlab.ui.container.Menu
        Menu_2       matlab.ui.container.Menu
        Menu_5       matlab.ui.container.Menu
        Menu_22      matlab.ui.container.Menu
        Menu_23      matlab.ui.container.Menu
        Menu_3       matlab.ui.container.Menu
        Menu_4       matlab.ui.container.Menu
        Menu_6       matlab.ui.container.Menu
        Menu_7       matlab.ui.container.Menu
        Menu_8       matlab.ui.container.Menu
        Menu_26      matlab.ui.container.Menu
        Menu_9       matlab.ui.container.Menu
        Menu_10      matlab.ui.container.Menu
        Menu_11      matlab.ui.container.Menu
        Menu_24      matlab.ui.container.Menu
        Menu_12      matlab.ui.container.Menu
        Menu_13      matlab.ui.container.Menu
        Menu_14      matlab.ui.container.Menu
        Menu_15      matlab.ui.container.Menu
        Menu_16      matlab.ui.container.Menu
        Menu_17      matlab.ui.container.Menu
        Menu_18      matlab.ui.container.Menu
        Menu_19      matlab.ui.container.Menu
        Menu_20      matlab.ui.container.Menu
        Menu_27      matlab.ui.container.Menu
        laplaceMenu  matlab.ui.container.Menu
        Menu_21      matlab.ui.container.Menu
        Menu_33      matlab.ui.container.Menu
        Menu_34      matlab.ui.container.Menu
        Menu_35      matlab.ui.container.Menu
        Menu_36      matlab.ui.container.Menu
        Menu_37      matlab.ui.container.Menu
        Menu_38      matlab.ui.container.Menu
        Menu_25      matlab.ui.container.Menu
        Menu_28      matlab.ui.container.Menu
        Menu_29      matlab.ui.container.Menu
        Menu_30      matlab.ui.container.Menu
        Menu_31      matlab.ui.container.Menu
        Menu_32      matlab.ui.container.Menu
        Menu_39      matlab.ui.container.Menu
        Menu_40      matlab.ui.container.Menu
        Menu_51      matlab.ui.container.Menu
        Menu_52      matlab.ui.container.Menu
        Menu_53      matlab.ui.container.Menu
        Menu_54      matlab.ui.container.Menu
        Menu_41      matlab.ui.container.Menu
        Menu_42      matlab.ui.container.Menu
        Menu_43      matlab.ui.container.Menu
        Menu_44      matlab.ui.container.Menu
        Menu_45      matlab.ui.container.Menu
        Menu_46      matlab.ui.container.Menu
        Menu_47      matlab.ui.container.Menu
        Menu_48      matlab.ui.container.Menu
        Menu_49      matlab.ui.container.Menu
        Menu_50      matlab.ui.container.Menu
        Menu_55      matlab.ui.container.Menu
        rmseMenu     matlab.ui.container.Menu
        huffmanMenu  matlab.ui.container.Menu
        Menu_56      matlab.ui.container.Menu
        Menu_58      matlab.ui.container.Menu
        Menu_59      matlab.ui.container.Menu
        Menu_57      matlab.ui.container.Menu
        Menu_60      matlab.ui.container.Menu
    end

    
    properties (Access = public)
        current_img % 当前图像
        imgStack % 存储图像历史的栈 
    end
    
    properties (Access = private)
        img % 初始图像
        last_img % 上一幅图像
        Fourier_spectrum % 图像傅里叶频谱
        degenerate_transfer % 退化传递函数
        degenerate % 退化传递函数（空域）
        estimated_nsr  % 信噪比
        img_d % 当前图像的小数形式
        huffmanDict % 图像的哈夫曼字典
        se % 形态学算子
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: Menu_2
        function Menu_2Selected(app, event)
            % 打开文件选择对话框，选择图像文件
            [file, path] = uigetfile({'*.bmp;*.jpg;*.png;*.tif;*.jfif;*.mat'}, 'Select an image file');

            % 如果用户没有选择文件，就退出函数
            if isequal(file, 0)
                return;
            end

            % 根据文件扩展名决定使用哪个函数读取文件
            [~, ~, ext] = fileparts(file);
            if strcmp(ext, '.mat')
                data = load(fullfile(path, file));
                app.img = data.img; % 假设 .mat 文件中的图像数据存储在变量 img 中
            else
                app.img = imread(fullfile(path, file));
            end

            % 创建一个新的图像窗口
            fig = figure("Position",[200 400 200 150]);

            % 设置图像窗口的位置和大小，例如：[左 下 宽 高]
            % 这里的数值是以像素为单位的
            % 显示图像
            imshow(app.img);
            app.current_img=app.img;
            app.last_img=app.img;

            % 将图像添加到栈中
            app.imgStack = {app.img};
        end

        % Callback function
        function ImageAxesButtonDown(app, event)
            % 获取鼠标点击的位置
            point = event.IntersectionPoint;

            % 显示点击的位置
            disp(['You clicked at x = ', num2str(point(1)), ', y = ', num2str(point(2))]);
        end

        % Menu selected function: Menu_3
        function Menu_3Selected(app, event)
            if isempty(app.img)
                uialert(app.UIFigure, '请先打开一个图像', '错误');
                return;
            end
            % 检查是否有图像可以保存
            if isempty(app.current_img)
                disp('No image to save.');
                return;
            end

            % 打开文件保存对话框，选择保存位置和文件名
            [file, path] = uiputfile({'*.bmp;*.jpg;*.png;*.tif;*.jfif;*.mat'}, 'Save image as');

            % 如果用户没有选择文件，就退出函数
            if isequal(file, 0)
                return;
            end

            % 保存图像
            imwrite(app.current_img, fullfile(path, file));
            disp('Image saved successfully.');
        end

        % Menu selected function: Menu_4
        function Menu_4Selected(app, event)
            if isempty(app.img)
                uialert(app.UIFigure, '请先打开一个图像', '错误');
                return;
            end
            % 关闭所有的图像窗口
            close all;
        end

        % Menu selected function: Menu_5
        function Menu_5Selected(app, event)
            if isempty(app.img)
                uialert(app.UIFigure, '请先打开一个图像', '错误');
                return;
            end
            % 检查是否有图像可以设置
            if isempty(app.img)
                disp('No image to set.');
                return;
            end

            % 设置 current_img 为 img
            app.current_img = app.img;
            disp('Image set successfully.');
            % 在进行操作后，将新的图像添加到栈中
            app.imgStack{end+1} = app.current_img;
        end

        % Menu selected function: Menu_7
        function Menu_7Selected(app, event)
            
        end

        % Menu selected function: Menu_8
        function Menu_8Selected(app, event)
            % 图像灰度反转
            app.last_img=app.current_img;
            % 检查是否有图像可以反转
            if isempty(app.current_img)
                disp('No image to invert.');
                return;
            end

            % 确保图像为灰度图像
            if size(app.current_img, 3) == 3
                img = rgb2gray(app.current_img);
            else
                img = app.current_img;
            end

            % 反转图像
            inverted_img = imcomplement(img);

            % 显示反转后的图像
            figure;
            imshow(inverted_img);
            title('Inverted Image');

            % 显示映射曲线
            figure;
            plot((0:255), (255:-1:0));
            title('Mapping Curve');
            xlabel('Original Intensity Values');
            ylabel('Inverted Intensity Values');

            % 更新 current_img
            app.current_img = inverted_img;
            disp('Image inverted successfully.');
            % 在进行操作后，将新的图像添加到栈中
            app.imgStack{end+1} = app.current_img;
        end

        % Menu selected function: Menu_22
        function Menu_22Selected(app, event)
            if isempty(app.img)
                uialert(app.UIFigure, '请先打开一个图像', '错误');
                return;
            end
             % 检查是否有图像可以设置
            if isempty(app.current_img)
                disp('No image to show.');
                return;
            end
             % 创建一个新的图像窗口
            figure("Position",[200 200 200 150]);

            % 设置图像窗口的位置和大小，例如：[左 下 宽 高]
            % 这里的数值是以像素为单位的
            % 显示图像
            imshow(app.current_img);
            title('当前图像');
        end

        % Menu selected function: Menu_23
        function Menu_23Selected(app, event)
            if isempty(app.img)
                uialert(app.UIFigure, '请先打开一个图像', '错误');
                return;
            end
            if numel(app.imgStack) > 1
                % 从栈中弹出最近的图像
                app.imgStack(end) = [];
                % 先将当前图像设置为栈顶的图像
                app.current_img = app.imgStack{end};

                % 检查是否有图像可以设置
                if isempty(app.last_img)
                    disp('No last image to show.');
                    return;
                end
            else
                uialert(app.UIFigure, '无法撤销', '错误');
            end
        end

        % Menu selected function: Menu_9
        function Menu_9Selected(app, event)
            % 图像对数变换
            % 创建初始映射曲线窗口
            figure;
            r = 0:0.01:1;
            c = 1; % 默认c值为1
            s = r;
            axes('Position', [0.1 0.1 0.7 0.8]); % 设置绘图区域的位置和大小
            hPlot = plot(r, s,'LineWidth',2);
            title('映射曲线');
            xlabel('r');
            ylabel('s');

            % 添加交互通道以设置c的值
            uicontrol('Style', 'text', 'String', 'c（默认为1）：', 'Position', [450 300 120 20]);
            hEdit = uicontrol('Style', 'edit', 'String', '1', 'Position', [480 280 60 20]);

            % 添加变换按钮
            hButton = uicontrol('Style', 'pushbutton', 'String', '变换', 'Position', [480 250 60 20]);

            % 设置按钮的回调函数
            set(hButton, 'Callback', {@transformImage, hEdit, hPlot, r});

            function transformImage(hObject, eventdata, hEdit, hPlot, r)
                app.last_img=app.current_img;
                % 检查是否有图像可以反转
                if isempty(app.current_img)
                    disp('No image to invert.');
                    return;
                end

                % 确保图像为灰度图像
                if size(app.current_img, 3) == 3
                    img = rgb2gray(app.current_img);
                else
                    img = app.current_img;
                end
                % 获取c的值
                c = str2double(get(hEdit, 'String'));

                % 更新映射曲线
                s = c * log(1 + r);
                set(hPlot, 'YData', s);

                % 对图像进行对数变换并展示
                %img = im2double(img); % 转换为双精度
                img_transformed = uint8(c*log(double(img)+1)); % 对数变换
                figure;
                imshow(img_transformed,[]); % 展示变换后的图像
                title(['变换后的图像' ' ' 'c=' num2str(c)]);
                max_img=max(img_transformed,[],'all');
                min_img=min(img_transformed,[],'all');
                % 线性变换
                img_transformed = imadjust(img_transformed, [double(min_img)/255 double(max_img)/255],[]);

                % 更新 current_img
                app.current_img = img_transformed;
                % 在进行操作后，将新的图像添加到栈中
                app.imgStack{end+1} = app.current_img;
            end
        end

        % Menu selected function: Menu_10
        function Menu_10Selected(app, event)
            % 图像幂律变换
            % 创建初始映射曲线窗口
            fig=figure;
            r = 0:0.01:1;
            c = 1; % 默认c值为1
            gamma = 1; % 默认gamma值为1
            s = c * r .^ gamma;
            axes('Position', [0.1 0.1 0.7 0.8]); % 设置绘图区域的位置和大小
            hPlot = plot(r, s,'LineWidth',2);
            title('映射曲线');
            xlabel('r');
            ylabel('s');

            % 添加交互通道以设置c和gamma的值
            uicontrol(fig,'Style', 'text', 'String', 'c（默认为1）：', 'Position', [450 300 120 20]);
            hEditC = uicontrol(fig,'Style', 'edit', 'String', '1', 'Position', [480 280 60 20]);
            uicontrol(fig,'Style', 'text', 'String','gamma（默认为1）：', 'Position', [450 260 150 20]);
            hEditGamma = uicontrol(fig,'Style', 'edit', 'String', '1', 'Position', [480 240 60 20]);

            % 添加变换按钮
            hButton = uicontrol(fig,'Style', 'pushbutton', 'String', '变换', 'Position', [480 210 60 20]);

            % 设置按钮的回调函数
            set(hButton, 'Callback', {@transformImage, hEditC, hEditGamma, hPlot, r});

             function transformImage(hObject, eventdata, hEditC, hEditGamma, hPlot, r)
                app.last_img=app.current_img;
                % 检查是否有图像可以反转
                if isempty(app.current_img)
                    disp('No image to invert.');
                    return;
                end

                % 确保图像为灰度图像
                if size(app.current_img, 3) == 3
                    img = rgb2gray(app.current_img);
                else
                    img = app.current_img;
                end
                % 获取c和gamma的值
                c = str2double(get(hEditC, 'String'));
                gamma = str2double(get(hEditGamma, 'String'));

                % 更新映射曲线
                s = c * r .^ gamma;
                set(hPlot, 'YData', s);
                % 对图像进行对数变换并展示
                %img = im2double(img); % 转换为双精度
                img_transformed=imadjust(img,[0,1],[0,1],gamma);%幂律变换
                figure;
                imshow(img_transformed,[]); % 展示变换后的图像
                title(['变换后的图像' ' ' 'c=' num2str(c) ' ' '\gamma=' num2str(gamma)]);

                % 更新 current_img
                app.current_img = img_transformed;
                % 在进行操作后，将新的图像添加到栈中
                app.imgStack{end+1} = app.current_img;
            end
        end

        % Menu selected function: Menu_11
        function Menu_11Selected(app, event)
            % 图像线性映射变换
            % 创建初始映射曲线窗口
            fig = figure;
            axes('Position', [0.1 0.1 0.7 0.8]); % 设置绘图区域的位置和大小
            r = 0:0.01:1;
            s = r;
            hPlot = plot(r, s, 'LineWidth', 2);
            hold on;
            hPoint1 = plot(0.3, 0.3, 'ro','MarkerFaceColor','auto', 'MarkerSize', 10, 'ButtonDownFcn', @startDragFcn);
            hPoint2 = plot(0.7, 0.7, 'ro','MarkerFaceColor','auto', 'MarkerSize', 10, 'ButtonDownFcn', @startDragFcn);
            title('映射曲线');
            xlabel('r');
            ylabel('s');

            % 添加变换按钮
            hButton = uicontrol(fig, 'Style', 'pushbutton', 'String', '变换', 'Position', [480 210 60 20]);

            % 设置按钮的回调函数
            set(hButton, 'Callback', {@transformImage, hPlot, hPoint1, hPoint2});

            function startDragFcn(src, ~)
                set(fig, 'WindowButtonMotionFcn', @(~, ~)draggingFcn(src, hPlot));
                set(fig, 'WindowButtonUpFcn', @stopDragFcn);
            end

            function draggingFcn(src, hPlot)
                pt = get(gca, 'CurrentPoint');
                set(src, 'XData', pt(1, 1), 'YData', pt(1, 2));
                updatePlot(hPlot, hPoint1, hPoint2);
            end

            function stopDragFcn(~, ~)
                set(fig, 'WindowButtonMotionFcn', '');
                set(fig, 'WindowButtonUpFcn', '');
            end

            function updatePlot(hPlot, hPoint1, hPoint2)
                x1 = get(hPoint1, 'XData');
                y1 = get(hPoint1, 'YData');
                x2 = get(hPoint2, 'XData');
                y2 = get(hPoint2, 'YData');
                r = 0:0.01:1;
                s = interp1([0, x1, x2, 1], [0, y1, y2, 1], r, 'linear');
                set(hPlot, 'YData', s);
            end

            function transformImage(~, ~, hPlot, hPoint1, hPoint2)
                app.last_img=app.current_img; % 记录上次图片
                x1 = get(hPoint1, 'XData');
                y1 = get(hPoint1, 'YData');
                x2 = get(hPoint2, 'XData');
                y2 = get(hPoint2, 'YData');
                r = 0:0.01:1;
                s = interp1([0, x1, x2, 1], [0, y1, y2, 1], r, 'linear');
                set(hPlot, 'YData', s);

                T=round(255*interp1([0, x1, x2, 1], [0, y1, y2, 1],linspace(0,1,256), 'linear'));
                % 对图像进行映射变换并展示
                img = app.current_img;
                img_transformed = T(img+1);
                figure;
                imshow(img_transformed, []); % 展示变换后的图像
                title('变换后的图像');
                % 更新 current_img
                app.current_img = img_transformed;
                % 在进行操作后，将新的图像添加到栈中
                app.imgStack{end+1} = app.current_img;
            end
        end

        % Menu selected function: Menu_24
        function Menu_24Selected(app, event)
            % 图像非线性映射变换
            % 创建初始映射曲线窗口
            fig = figure;
            axes('Position', [0.1 0.1 0.7 0.8]); % 设置绘图区域的位置和大小
            r = 0:0.01:1;
            s = r;
            hPlot = plot(r, s, 'LineWidth', 2);
            hold on;
            hPoint1 = plot(0.3, 0.3, 'ro','MarkerFaceColor','auto', 'MarkerSize', 10, 'ButtonDownFcn', @startDragFcn);
            hPoint2 = plot(0.7, 0.7, 'ro','MarkerFaceColor','auto', 'MarkerSize', 10, 'ButtonDownFcn', @startDragFcn);
            title('映射曲线');
            xlabel('r');
            ylabel('s');

            % 添加变换按钮
            hButton = uicontrol(fig, 'Style', 'pushbutton', 'String', '变换', 'Position', [480 210 60 20]);

            % 设置按钮的回调函数
            set(hButton, 'Callback', {@transformImage, hPlot, hPoint1, hPoint2});

            function startDragFcn(src, ~)
                set(fig, 'WindowButtonMotionFcn', @(~, ~)draggingFcn(src, hPlot));
                set(fig, 'WindowButtonUpFcn', @stopDragFcn);
            end

            function draggingFcn(src, hPlot)
                pt = get(gca, 'CurrentPoint');
                set(src, 'XData', pt(1, 1), 'YData', pt(1, 2));
                updatePlot(hPlot, hPoint1, hPoint2);
            end

            function stopDragFcn(~, ~)
                set(fig, 'WindowButtonMotionFcn', '');
                set(fig, 'WindowButtonUpFcn', '');
            end

            function updatePlot(hPlot, hPoint1, hPoint2)
                x1 = get(hPoint1, 'XData');
                y1 = get(hPoint1, 'YData');
                x2 = get(hPoint2, 'XData');
                y2 = get(hPoint2, 'YData');
                r = 0:0.01:1;
                s = interp1([0, x1, x2, 1], [0, y1, y2, 1], r, 'pchip');
                set(hPlot, 'YData', s);
            end

            function transformImage(~, ~, hPlot, hPoint1, hPoint2)
                app.last_img=app.current_img; % 记录上次图片
                x1 = get(hPoint1, 'XData');
                y1 = get(hPoint1, 'YData');
                x2 = get(hPoint2, 'XData');
                y2 = get(hPoint2, 'YData');
                r = 0:0.01:1;
                s = interp1([0, x1, x2, 1], [0, y1, y2, 1], r, 'pchip');
                set(hPlot, 'YData', s);

                T=round(255*interp1([0, x1, x2, 1], [0, y1, y2, 1],linspace(0,1,256), 'pchip'));
                % 对图像进行映射变换并展示
                img = app.current_img;
                img_transformed = T(img+1);
                figure;
                imshow(img_transformed, []); % 展示变换后的图像
                title('变换后的图像');
                % 更新 current_img
                app.current_img = uint8(img_transformed);
                % 在进行操作后，将新的图像添加到栈中
                app.imgStack{end+1} = app.current_img;
            end
        end

        % Menu selected function: Menu_13
        function Menu_13Selected(app, event)
            % 显示直方图
            % 检查是否有图像可以显示直方图
            if isempty(app.current_img)
                disp('No image to display histogram.');
                return;
            end

            % 确保图像为灰度图像
            img = app.current_img;
            if size(img, 3) == 3
                img = rgb2gray(img);
            end

            % 显示图像的直方图
            figure;
            imhist(img);
            title('图像直方图');
        end

        % Menu selected function: Menu_14
        function Menu_14Selected(app, event)
            % 直方图均衡化
            % 记录图像
            app.last_img=app.current_img; 
            % 检查是否有图像可以进行直方图均衡化
            if isempty(app.current_img)
                disp('No image to equalize histogram.');
                return;
            end

            % 确保图像为灰度图像
            img = app.current_img;
            if size(img, 3) == 3
                img = rgb2gray(img);
            end

            % 进行直方图均衡化
            img_eq = histeq(img);

            % 创建一个新的图窗来显示两个图像
            figure;
            subplot(1, 2, 1); % 左边的图像
            imshow(img_eq);
            title('均衡化后的图像');

            subplot(1, 2, 2); % 右边的图像
            imhist(img_eq);
            title('均衡化后的直方图');

            % 更新 current_img
            app.current_img = img_eq;
            % 在进行操作后，将新的图像添加到栈中
            app.imgStack{end+1} = app.current_img;
        end

        % Menu selected function: Menu_15
        function Menu_15Selected(app, event)
            % 记录图像
            app.last_img=app.current_img;
            % 直方图规定化
            % 创建一个新的图形窗口
            f = figure;

            % 初始化图像数据
            f.UserData.originalImage = app.current_img;
            f.UserData.specifiedImage = [];

            % 创建四个子图
            ax1 = subplot(2, 2, 1); % 左上角
            title('原始图像的直方图');
            imhist(f.UserData.originalImage);

            ax2 = subplot(2, 2, 2); % 右上角
            title('指定图像的直方图');

            ax3 = subplot(2, 2, 3); % 左下角
            title('直方图规定化后的直方图');

            ax4 = subplot(2, 2, 4); % 右下角
            title('均衡化后的图像');

            % 创建两个按钮
            uicontrol('Style', 'pushbutton', 'String', '选择指定图像',...
                'Position', [180 5 100 20],...
                'Callback', @selectImage);

            uicontrol('Style', 'pushbutton', 'String', '均衡化',...
                'Position', [350 5 100 20],...
                'Callback', @equalizeHistogram);


            function selectImage(src, event)
                % 选择图像
                [filename, pathname] = uigetfile('*.jpg;*.png;*.bmp', '选择指定图像');
                if isequal(filename, 0) || isequal(pathname, 0)
                    return;
                end
                img = imread(fullfile(pathname, filename));

                % 计算并显示直方图
                axes(ax2); % 切换到指定的轴
                imhist(img); % 显示直方图
                title(ax2, '指定图像的直方图');

                % 保存图像数据
                f.UserData.specifiedImage = img;
            end

            function equalizeHistogram(src, event)
                % 检查是否已选择图像
                if isempty(f.UserData.specifiedImage)
                    errordlg('请先选择指定图像。', '错误');
                    return;
                end

                % 均衡化直方图
                hist_img = histeq(f.UserData.originalImage, imhist(f.UserData.specifiedImage));

                % 显示均衡化后的直方图和图像
                axes(ax3); % 切换到指定的轴
                imhist(hist_img); % 显示直方图

                title(ax3, '直方图规定化后的直方图');
                %axes(ax4); % 切换到指定的轴
                imshow(hist_img, 'Parent', ax4);
                title(ax4, '均衡化后的图像');

                % 更新 current_img
                app.current_img = hist_img;
                % 在进行操作后，将新的图像添加到栈中
                app.imgStack{end+1} = app.current_img;
            end
        end

        % Menu selected function: Menu_16
        function Menu_16Selected(app, event)
            %局部直方图均衡化
            % 记录图像
            app.last_img=app.current_img;

            % 创建一个新的图形窗口
            f = figure;

            % 初始化图像数据
            f.UserData.originalImage = app.current_img;
            f.UserData.Size = 0;

            % 创建三个子图
            subplot(1, 3, 1); % 左边
            title('原始图像的直方图');
            imhist(f.UserData.originalImage);

            ax2 = subplot(1, 3, 2); % 中间
            title('局部直方图均衡化后的直方图');

            ax3 = subplot(1, 3, 3); % 右边
            title('局部直方图均衡化后的图像');

            % 创建输入框和按钮
            uicontrol(f,'Style', 'text', 'String', 'Size=', 'Position', [130 5 80 20]);
            src=uicontrol(f,'Style', 'edit', 'String', '1', 'Position', [190 5 60 20]);

            hButton = uicontrol('Style', 'pushbutton', 'String', '均衡化',...
                'Position', [300 5 100 20]);
            % 添加变换按钮
           
            % 将src保存到按钮的UserData属性中
            hButton.UserData = src;

            % 设置按钮的回调函数
            set(hButton, 'Callback', @equalizeHistogram);

            function equalizeHistogram(src, ~)
                
                % 从按钮的UserData属性中获取src
                src = src.UserData;
                % 检查是否已输入Size
                Size = str2double(get(src, 'String'));
                if isnan(Size)
                    errordlg('请输入有效的Size。', '错误');
                    return;
                end

                % 局部直方图均衡化
                img_part = part_hist_equal(f.UserData.originalImage,Size);

                % 显示均衡化后的直方图和图像
                axes(ax2); % 切换到指定的轴
                imhist(img_part); % 显示直方图

                axes(ax3); % 切换到指定的轴
                imshow(img_part); % 显示图像

                % 更新 current_img
                app.current_img = img_part;
                % 在进行操作后，将新的图像添加到栈中
                app.imgStack{end+1} = app.current_img;

                function img_out = part_hist_equal(img, Size)
                    [m,n,k] = size(img);
                    if k == 1
                        img_out = img;
                        img_fill = [zeros((Size - 1) / 2, n + (Size - 1)); zeros(m, (Size - 1) / 2), img, zeros(m, (Size - 1) / 2); zeros((Size - 1) / 2, n + (Size - 1))];
                        img = uint16(img);
                        for i = 1:m
                            for j = 1:n
                                s = field_histeq(img_fill, i, j, Size);
                                img_out(i,j) = s(img(i,j) + 1);
                            end
                        end
                    end
                end

                function s = field_histeq(img, x, y, Size)
                    field_img = img(x:x + Size - 1, y:y + Size - 1);
                    h = imhist(field_img) / numel(field_img);
                    s = 0:length(h) - 1;
                    rk = 0.0;
                    for i = 1:length(h)
                        rk = rk + h(i);
                        s(i) = round(rk * (length(h) - 1));
                    end
                end

            end
        end

        % Menu selected function: Menu_17
        function Menu_17Selected(app, event)
            % 局部统计量增强
            % 记录图像
            app.last_img=app.current_img;
            
            % 创建一个新的图形窗口
            f = figure;

            % 初始化图像数据
            f.UserData.originalImage = app.current_img;
            % 创建两个子图
            subplot(1, 2, 1); % 左边
            title('原始图像');
            imshow(f.UserData.originalImage);

            ax2 = subplot(1, 2, 2); % 右边
            title('局部统计量增强后的图像');

            % 创建输入框和按钮
            uicontrol(f,'Style', 'text', 'String', 'k_0=', 'Position', [20 5 40 20]);
            src_k0 = uicontrol('Style', 'edit', 'String', '0', 'Position', [60 5 30 20]);
            uicontrol(f,'Style', 'text', 'String', 'k_1=', 'Position', [100 5 40 20]);
            src_k1 = uicontrol('Style', 'edit', 'String', '0', 'Position', [140 5 30 20]);
            uicontrol(f,'Style', 'text', 'String', 'k_2=', 'Position', [180 5 40 20]);
            src_k2 = uicontrol('Style', 'edit', 'String', '0', 'Position', [220 5 30 20]);
            uicontrol(f,'Style', 'text', 'String', 'k_3=', 'Position', [260 5 40 20]);
            src_k3 = uicontrol('Style', 'edit', 'String', '0', 'Position', [300 5 30 20]);
            uicontrol(f,'Style', 'text', 'String', 'C=', 'Position', [340 5 40 20]);
            src_C = uicontrol('Style', 'edit', 'String', '0', 'Position', [380 5 30 20]);

            hButton = uicontrol('Style', 'pushbutton', 'String', '增强',...
                'Position', [420 5 100 20]);

            % 将src保存到按钮的UserData属性中
            hButton.UserData = struct('k0', src_k0, 'k1', src_k1, 'k2', src_k2, 'k3', src_k3,'C',src_C);

            % 设置按钮的回调函数
            set(hButton, 'Callback', @enhance);

            function enhance(src, ~)
                % 从按钮的UserData属性中获取src
                src = src.UserData;

                % 检查是否已输入k0, k1, k2, k3
                k0 = str2double(get(src.k0, 'String'));
                k1 = str2double(get(src.k1, 'String'));
                k2 = str2double(get(src.k2, 'String'));
                k3 = str2double(get(src.k3, 'String'));
                C = str2double(get(src.C, 'String'));
                if any(isnan([k0, k1, k2, k3 C]))
                    errordlg('请输入有效的k0, k1, k2, k3，C。', '错误');
                    return;
                end

                % 局部统计量增强
                % 这里添加你的局部统计量增强代码
                img_enhanced = part_enhance(f.UserData.originalImage,k0,k1,k2,k3,C);

                % 显示增强后的图像
                axes(ax2); % 切换到指定的轴
                imshow(img_enhanced); % 显示图像
                title('局部统计量增强后的图像');

                % 更新 current_img
                app.current_img = img_enhanced;
                % 在进行操作后，将新的图像添加到栈中
                app.imgStack{end+1} = app.current_img;

                function g=part_enhance(img,k_0,k_1,k_2,k_3,E)
                    %使用直方图统计量局部增强图像
                    g=img;
                    %[m,sigma]=get_statistic(img);
                    m=mean(double(img),"all");
                    sigma=std(double(img),0,"all");
                    [x,y]=size(img);
                    Size=3;
                    img_fill=wextend(2,'zpd',img,(Size-1)/2);
                    for i=1:x
                        for j=1:y
                            m_part=mean(double(img_fill(i:i+Size-1,j:j+Size-1)),"all");
                            sigma_part=std(double(img_fill(i:i+Size-1,j:j+Size-1)),0,"all");
                            %[m_part,sigma_part]=get_statistic(img_fill(i:i+Size-1,j:j+Size-1));
                            if (m_part>=k_0*m&&m_part<=k_1*m)||(sigma_part>=k_2*sigma&&sigma_part<=k_3*sigma)
                                g(i,j)=E*img(i,j);
                            end
                        end
                    end
                end
            end
        end

        % Menu selected function: Menu_25
        function Menu_25Selected(app, event)
            % 比特平面切割
            % 记录图像
            app.last_img=app.current_img;
            
            % 创建一个新的图形窗口
            f = figure;

            % 初始化图像数据
            f.UserData.originalImage = app.current_img;
            % 创建九个子图
            ax = cell(1, 9);
            for i = 1:9
                ax{i} = subplot(3, 3, i);
            end

            [h,w]=size(f.UserData.originalImage);
            m=dec2bin(f.UserData.originalImage);
            [~,k_m]=size(m);
            img_bit=zeros(h,w,8);
            for k=1:k_m
                for i=1:w
                    for j=1:h
                            img_bit(j,i,k)=uint8(m(h*(i-1)+j,k_m+1-k)-48);
                    end
                end
            end
            for i=1:8
                % 显示比特平面
                imshow(img_bit(:,:,i), 'Parent', ax{i});
                title(ax{i}, ['比特平面', num2str(i)]);
            end

            % 创建输入框和按钮
            src_start = uicontrol('Style', 'edit', 'String', '1', 'Position', [100 5 100 20]);
            src_end = uicontrol('Style', 'edit', 'String', '8', 'Position', [250 5 100 20]);

            hButton = uicontrol('Style', 'pushbutton', 'String', '组合',...
                'Position', [400 5 100 20]);

            % 将src保存到按钮的UserData属性中
            hButton.UserData = struct('start', src_start, 'end', src_end,'img_bit',img_bit);

            % 设置按钮的回调函数
            set(hButton, 'Callback', @combine);


            function combine(src, ~)
                % 从按钮的UserData属性中获取src
                src = src.UserData;

                % 检查是否已输入开始和结束
                start = str2double(get(src.start, 'String'));
                end_ = str2double(get(src.end, 'String'));
                img_bit=src.img_bit;
                if any(isnan([start, end_]))
                    errordlg('请输入有效的开始和结束。', '错误');
                    return;
                end

                % 比特平面叠加
                img_combined = zeros(size(f.UserData.originalImage));
                for i = start:end_
                    img_combined = img_combined + double(img_bit(:,:,i))*2^(i-1);
                end
                img_combined = uint8(img_combined);

                % 显示组合图片
                imshow(img_combined, 'Parent', ax{9});
                title(ax{9}, ['从' num2str(start) '到' num2str(end_) '的组合图片']);

                % 更新 current_img
                app.current_img = img_combined;
                % 在进行操作后，将新的图像添加到栈中
                app.imgStack{end+1} = app.current_img;
            end
        end

        % Menu selected function: Menu_26
        function Menu_26Selected(app, event)
            % 线性变换
            % 记录图像
            app.last_img=app.current_img;
            
            % 创建一个新的图形窗口
            f = figure;

             % 初始化图像数据
            f.UserData.originalImage = app.current_img;

            % 创建两个子图;
            ax1 = subplot(1, 2, 1); % 左边

            ax2 = subplot(1, 2, 2); % 右边
            title('变换后的图像');

            % 创建输入框和按钮
            uicontrol('Style', 'text', 'String', 'min=', 'Position', [100 5 40 20]);
            src_start = uicontrol('Style', 'edit', 'String', '0', 'Position', [140 5 50 20]);
            uicontrol('Style', 'text', 'String', 'max=', 'Position', [200 5 40 20]);
            src_end = uicontrol('Style', 'edit', 'String', '255', 'Position', [240 5 50 20]);

            hButton = uicontrol('Style', 'pushbutton', 'String', '变换',...
                'Position', [350 5 100 20]);

            % 将src保存到按钮的UserData属性中
            hButton.UserData = struct('start', src_start, 'end', src_end);

            % 设置按钮的回调函数
            set(hButton, 'Callback', @transform);

            % 显示原始图像
            imshow(f.UserData.originalImage, 'Parent', ax1);
            title('原始图像','Parent', ax1);

            function transform(src, ~)
                % 从按钮的UserData属性中获取src
                src = src.UserData;

                % 检查是否已输入起点和终点
                start = str2double(get(src.start, 'String'));
                end_ = str2double(get(src.end, 'String'));
                if any(isnan([start, end_]))
                    errordlg('请输入有效的起点和终点。', '错误');
                    return;
                end

                max_img=max(f.UserData.originalImage,[],'all');
                min_img=min(f.UserData.originalImage,[],'all');
                % 线性变换
                img_transformed = imadjust(f.UserData.originalImage, [double(min_img)/255 double(max_img)/255], [start/255 end_/255]);

                % 显示变换后的图像
                imshow(img_transformed, 'Parent', ax2);
                title('变换后的图像');

                % 更新 current_img
                app.current_img = img_transformed;
                % 在进行操作后，将新的图像添加到栈中
                app.imgStack{end+1} = app.current_img;
            end
        end

        % Menu selected function: Menu_19
        function Menu_19Selected(app, event)
            % 盒式滤波
            % 记录图像
            app.last_img=app.current_img;
            
            % 创建figure
            fig = uifigure('Name', 'Box Filter Application');

            % 创建axes用于显示原始图像和滤波后的图像
            ax1 = uiaxes(fig, 'Position', [20 150 200 200]);
            ax2 = uiaxes(fig, 'Position', [250 150 200 200]);

            % 创建文本框和输入框
            uilabel(fig, 'Position', [20 50 50 20], 'Text', 'Size=');
            editField = uieditfield(fig, 'numeric', 'Position', [70 50 50 20]);

            % 创建下拉菜单用于选择Padding pattern
            uilabel(fig, 'Position', [320 70 100 20], 'Text', 'Padding pattern:');
            dropdown = uidropdown(fig, 'Position', [430 70 100 20], 'Items', {'circular', 'replicate', 'symmetric'}, 'Value', 'replicate');

            % 创建滤波按钮
            button = uibutton(fig, 'push', 'Position', [200 50 80 20], 'Text', '滤波');
            button.ButtonPushedFcn = @(btn,event) filterImage(editField, dropdown, ax1, ax2);

            % 加载并显示原始图像
            img = app.current_img;
            imshow(img, 'Parent', ax1);
            title('原始图像','Parent', ax1);

            function filterImage(editField, dropdown, ax1, ax2)
                % 获取Size值
                sizeValue = editField.Value;

                % 获取Padding pattern
                paddingPattern = dropdown.Value;

                % 获取原始图像
                originalImage = getimage(ax1);

                % 对原始图像进行盒式滤波
                filteredImage = imboxfilt(originalImage, sizeValue, 'Padding', paddingPattern);

                % 显示滤波后的图像
                imshow(filteredImage, 'Parent', ax2);
                title(['盒式滤波 Size=' num2str(sizeValue)],'Parent', ax2);

                % 更新 current_img
                app.current_img = filteredImage;
                % 在进行操作后，将新的图像添加到栈中
                app.imgStack{end+1} = app.current_img;
            end
        end

        % Menu selected function: Menu_20
        function Menu_20Selected(app, event)
            % 高斯滤波
            % 记录图像
            app.last_img=app.current_img;
            % 创建figure
            fig = uifigure('Name', 'Gaussian Filter Application');

            % 创建axes用于显示原始图像和滤波后的图像
            ax1 = uiaxes(fig, 'Position', [20 150 200 200]);
            ax2 = uiaxes(fig, 'Position', [250 150 200 200]);

            % 创建文本框和输入框
            uilabel(fig, 'Position', [20 50 50 20], 'Text', 'Size=');
            sizeField = uieditfield(fig, 'numeric', 'Position', [70 50 40 20]);

            uilabel(fig, 'Position', [130 50 50 20], 'Text', 'Sigma=');
            sigmaField = uieditfield(fig, 'numeric', 'Position', [180 50 50 20]);

            % 创建下拉菜单用于选择Padding pattern
            uilabel(fig, 'Position', [250 50 100 20], 'Text', 'Padding pattern:');
            dropdown = uidropdown(fig, 'Position', [350 50 100 20], 'Items', {'circular', 'replicate', 'symmetric'}, 'Value', 'replicate');

            % 创建滤波按钮
            button = uibutton(fig, 'push', 'Position', [470 50 50 20], 'Text', '滤波');
            button.ButtonPushedFcn = @(btn,event) filterImage(sizeField, sigmaField, dropdown, ax1, ax2);

            % 加载并显示原始图像
            img = app.current_img;
            imshow(img, 'Parent', ax1);
            title('原始图像','Parent', ax1);
            function filterImage(sizeField, sigmaField, dropdown, ax1, ax2)
                % 获取Size值和Sigma值
                sizeValue = sizeField.Value;
                sigmaValue = sigmaField.Value;

                % 获取Padding pattern
                paddingPattern = dropdown.Value;

                % 获取原始图像
                originalImage = getimage(ax1);

                % 对原始图像进行高斯滤波
                filteredImage = imgaussfilt(originalImage, sigmaValue, 'FilterSize', sizeValue, 'Padding', paddingPattern);

                % 显示滤波后的图像
                imshow(filteredImage, 'Parent', ax2);
                title(['高斯滤波 Size=' num2str(sizeValue) 'Sigma=' num2str(sigmaValue)],'Parent', ax2);

                % 更新 current_img
                app.current_img = filteredImage;
                % 在进行操作后，将新的图像添加到栈中
                app.imgStack{end+1} = app.current_img;
            end
        end

        % Menu selected function: Menu_27
        function Menu_27Selected(app, event)
            % 梯度算子
            % 记录图像
            app.last_img=app.current_img;
            
            % 创建figure
            fig = uifigure('Name', 'Gradient Operator Application');

            % 创建axes用于显示原始图像和处理后的图像
            ax1 = uiaxes(fig, 'Position', [20 150 200 200]);
            ax2 = uiaxes(fig, 'Position', [250 150 200 200]);

            % 创建下拉菜单用于选择梯度算子方法
            uilabel(fig, 'Position', [100 50 100 20], 'Text', 'Method:');
            dropdown = uidropdown(fig, 'Position', [200 50 100 20], 'Items', {'sobel', 'prewitt', 'central', 'intermediate', 'roberts'}, 'Value', 'sobel');

            % 创建处理按钮
            button = uibutton(fig, 'push', 'Position', [320 50 80 20], 'Text', '处理');
            button.ButtonPushedFcn = @(btn,event) processImage(dropdown, ax1, ax2);

            % 加载并显示原始图像
            img = app.current_img;
            imshow(img, 'Parent', ax1);
            title('原始图像','Parent', ax1);
            function processImage(dropdown, ax1, ax2)
                % 获取梯度算子方法
                method = dropdown.Value;

                % 获取原始图像
                originalImage = getimage(ax1);

                % 根据选择的梯度算子方法对原始图像进行处理
                processedImage = imgradient(originalImage, method);

                % 显示处理后的图像
                imshow(processedImage, 'Parent', ax2);
                title(method,'Parent', ax2);

                % 更新 current_img
                app.current_img = processedImage;
                % 在进行操作后，将新的图像添加到栈中
                app.imgStack{end+1} = app.current_img;
            end
        end

        % Menu selected function: laplaceMenu
        function laplaceMenuSelected(app, event)
            % laplace算子
            % 记录图像
            app.last_img=app.current_img;
            
            % 创建figure
            fig = uifigure('Name', 'Laplace Operator Application','Position',[400,300,640,300]);

            % 创建axes用于显示原始图像、Laplace算子和叠加后的图像
            ax1 = uiaxes(fig, 'Position', [20 50 200 200]);
            ax2 = uiaxes(fig, 'Position', [220 50 200 200]);
            ax3 = uiaxes(fig, 'Position', [420 50 200 200]);

            % 加载并显示原始图像
            img = app.current_img;
            imshow(img, 'Parent', ax1);
            title('原始图像','Parent', ax1);

            % 计算Laplace算子
            laplacianOperator = fspecial('laplacian',0);
            laplacianImage = imfilter(img, laplacianOperator, 'replicate');
            L_show = imfilter(double(img), laplacianOperator, 'replicate');

            min_L_A_show=min(L_show,[],"all");max_L_A_show=max(L_show-min_L_A_show,[],"all");
            L_A_show=uint8(255*(L_show-min_L_A_show)/max_L_A_show);
            % 显示Laplace算子
            imshow(L_A_show, 'Parent', ax2);
            title('laplace算子','Parent', ax2);

            % 计算并显示叠加后的图像
            combinedImage = img - laplacianImage;
            imshow(combinedImage, 'Parent', ax3);
            title('laplace叠加后的图像','Parent', ax3);
            % 更新 current_img
            app.current_img = combinedImage;
            % 在进行操作后，将新的图像添加到栈中
            app.imgStack{end+1} = app.current_img;
        end

        % Menu selected function: Menu_21
        function Menu_21Selected(app, event)
            % 双边滤波器
            % 记录图像
            app.last_img=app.current_img;
            % 创建figure
            fig = uifigure('Name', 'Bilateral Filter Application');

            % 创建axes用于显示原始图像和处理后的图像
            ax1 = uiaxes(fig, 'Position', [20 100 250 250]);
            ax2 = uiaxes(fig, 'Position', [300 100 250 250]);

            % 创建文本框和输入框
            uilabel(fig, 'Position', [20 50 120 20], 'Text', 'degreeofSmoothing=');
            degreeField = uieditfield(fig, 'numeric', 'Position', [140 50 50 20]);

            uilabel(fig, 'Position', [240 50 80 20], 'Text', 'SpatialSigma=');
            sigmaField = uieditfield(fig, 'numeric', 'Position', [320 50 50 20], 'Value', 1);

            % 创建滤波按钮
            button = uibutton(fig, 'push', 'Position', [400 50 60 20], 'Text', '滤波');
            button.ButtonPushedFcn = @(btn,event) processImage(degreeField, sigmaField, ax1, ax2);
            
            % 加载并显示原始图像
            img = app.current_img;
            imshow(img, 'Parent', ax1);
            title('原始图像','Parent', ax1);

            function processImage(degreeField, sigmaField, ax1, ax2)
                % 获取Degree of Smoothing和Spatial Sigma值
                degreeOfSmoothing = degreeField.Value;
                spatialSigma = sigmaField.Value;

                % 获取原始图像
                originalImage = getimage(ax1);

                % 根据输入的参数对图像进行双边滤波
                filteredImage = imbilatfilt(originalImage, degreeOfSmoothing, spatialSigma);

                % 显示滤波后的图像
                imshow(filteredImage, 'Parent', ax2);
                title('双边滤波器','Parent', ax2);

                % 更新 current_img
                app.current_img = filteredImage;
                % 在进行操作后，将新的图像添加到栈中
                app.imgStack{end+1} = app.current_img;
            end
        end

        % Menu selected function: Menu_6
        function Menu_6Selected(app, event)
            if isempty(app.img)
                uialert(app.UIFigure, '请先打开一个图像', '错误');
                return;
            end
        end

        % Menu selected function: Menu_29
        function Menu_29Selected(app, event)
            %图像平移
            % 记录图像
            app.last_img=app.current_img;
            % 创建一个新的 figure 窗口
            fig = uifigure('Name', '图像平移');

            % 创建axes用于显示原始图像和处理后的图像
            ax1 = uiaxes(fig, 'Position', [20 100 250 250]);
            ax2 = uiaxes(fig, 'Position', [280 100 250 250]);

            % 创建一个标签和一个编辑字段用于输入 x 方向的平移像素
            uilabel(fig, 'Position', [50 50 20 20], 'Text', 'x:');
            xEditField = uieditfield(fig, 'numeric', 'Position', [80 50 50 20],'Value',0);

            % 创建一个标签和一个编辑字段用于输入 y 方向的平移像素
            uilabel(fig, 'Position', [200 50 20 20], 'Text', 'y:');
            yEditField = uieditfield(fig, 'numeric', 'Position', [230 50 50 20],'Value',0);

            % 创建一个按钮，点击后进行图像平移
            button = uibutton(fig, 'push', 'Position', [350 50 50 20], 'Text', '平移');
            button.ButtonPushedFcn = @(btn,event) translateImage(btn, xEditField, yEditField);

             % 加载并显示原始图像
            img = app.current_img;
            imshow(img, 'Parent', ax1);
            title('原始图像','Parent', ax1);

            function translateImage(btn, xEditField, yEditField)
                % 获取输入的 x 和 y 值
                x = xEditField.Value;
                y = yEditField.Value;

                % 创建平移矩阵
                tform = affine2d([1 0 0; 0 1 0; x y 1]);
                [rows,cols,~]=size(img);

                % 应用平移矩阵
                outputImage = imwarp(img, tform,'OutputView', imref2d([rows, cols]));

                % 显示平移后的图像
                imshow(outputImage,'Parent',ax2);

                title(['平移 x=' num2str(x) ',y=' num2str(y)],'Parent',ax2);

                % 更新 current_img
                app.current_img = outputImage;
                % 在进行操作后，将新的图像添加到栈中
                app.imgStack{end+1} = app.current_img;
            end
        end

        % Menu selected function: Menu_30
        function Menu_30Selected(app, event)
            %图像旋转
            % 记录图像
            app.last_img=app.current_img;
            % 创建一个新的 figure 窗口
            fig = uifigure('Name', '图像旋转');

            % 创建axes用于显示原始图像和处理后的图像
            ax1 = uiaxes(fig, 'Position', [20 100 250 250]);
            ax2 = uiaxes(fig, 'Position', [280 100 250 250]);

            % 创建一个标签和一个编辑字段用于输入旋转的角度
            uilabel(fig, 'Position', [150 50 50 20], 'Text', 'theta:');
            thetaEditField = uieditfield(fig, 'numeric', 'Position', [210 50 50 20],'Value',0);

            % 创建一个按钮，点击后进行图像旋转
            button = uibutton(fig, 'push', 'Position', [350 50 50 20], 'Text', '旋转');
            button.ButtonPushedFcn = @(btn,event) translateImage(btn, thetaEditField);

            % 加载并显示原始图像
            img = app.current_img;
            imshow(img, 'Parent', ax1);
            title('原始图像','Parent', ax1);

            function translateImage(btn, thetaEditField)
                % 获取输入的 theta 值
                theta = thetaEditField.Value;

                % 创建平移矩阵
                tform = affine2d([cosd(theta) -sind(theta) 0; sind(theta) cosd(theta) 0; 0 0 1]);
                [rows,cols,~]=size(img);

                % 应用平移矩阵
                outputImage = imwarp(img, tform);

                % 显示平移后的图像
                imshow(outputImage,'Parent',ax2);

                title(['旋转 theta=' num2str(theta)],'Parent',ax2);

                % 更新 current_img
                app.current_img = outputImage;
                % 在进行操作后，将新的图像添加到栈中
                app.imgStack{end+1} = app.current_img;
            end
        end

        % Menu selected function: Menu_32
        function Menu_32Selected(app, event)
            %图像缩放
            % 记录图像
            app.last_img=app.current_img;
            % 创建一个新的 figure 窗口
            fig = uifigure('Name', '图像缩放');

            % 创建axes用于显示原始图像和处理后的图像
            ax1 = uiaxes(fig, 'Position', [20 100 250 250]);
            ax2 = uiaxes(fig, 'Position', [280 100 250 250]);

            % 创建一个标签和一个编辑字段用于输入 x 方向的缩放倍数
            uilabel(fig, 'Position', [50 50 20 20], 'Text', 'x:');
            xEditField = uieditfield(fig, 'numeric', 'Position', [80 50 50 20],'Value',0);

            % 创建一个标签和一个编辑字段用于输入 y 方向的缩放倍数
            uilabel(fig, 'Position', [200 50 20 20], 'Text', 'y:');
            yEditField = uieditfield(fig, 'numeric', 'Position', [230 50 50 20],'Value',0);

            % 创建一个按钮，点击后进行图像缩放
            button = uibutton(fig, 'push', 'Position', [350 50 50 20], 'Text', '缩放');
            button.ButtonPushedFcn = @(btn,event) translateImage(btn, xEditField, yEditField);

            % 加载并显示原始图像
            img = app.current_img;
            imshow(img, 'Parent', ax1);
            title('原始图像','Parent', ax1);

            function translateImage(btn, xEditField, yEditField)
                % 获取输入的 x 和 y 值
                x = xEditField.Value;
                y = yEditField.Value;

                % 创建平移矩阵
                tform = affine2d([x 0 0; 0 y 0; 0 0 1]);
                [rows,cols,~]=size(img);

                % 应用平移矩阵
                outputImage = imwarp(img, tform,'OutputView', imref2d([rows*x, cols*y]));

                % 显示平移后的图像
                imshow(outputImage,'Parent',ax2);

                title(['缩放 x=' num2str(x) ',y=' num2str(y)],'Parent',ax2);

                % 更新 current_img
                app.current_img = outputImage;
                % 在进行操作后，将新的图像添加到栈中
                app.imgStack{end+1} = app.current_img;
            end
        end

        % Menu selected function: Menu_31
        function Menu_31Selected(app, event)
            %图像镜像
            % 记录图像
            app.last_img=app.current_img;
            % 创建一个新的 figure 窗口
            fig = uifigure('Name', '图像镜像');

            % 创建axes用于显示原始图像和处理后的图像
            ax1 = uiaxes(fig, 'Position', [10 20 290 300]);
            ax2 = uiaxes(fig, 'Position', [300 20 290 300]);

            % 加载并显示原始图像
            img = app.current_img;
            imshow(img, 'Parent', ax1);
            title('原始图像','Parent', ax1);
            % 进行镜像变换
            outputImage = flip(img, 2);

            % 显示平移后的图像
            imshow(outputImage,'Parent',ax2);

            title('镜像变换','Parent',ax2);

            % 更新 current_img
            app.current_img = outputImage;
            % 在进行操作后，将新的图像添加到栈中
            app.imgStack{end+1} = app.current_img;
        end

        % Menu selected function: Menu_33
        function Menu_33Selected(app, event)
            % 最大值滤波
            % 记录图像
            app.last_img=app.current_img;

            % 创建figure
            fig = uifigure('Name', 'Maxvalue Filter Application');

            % 创建axes用于显示原始图像和滤波后的图像
            ax1 = uiaxes(fig, 'Position', [20 150 200 200]);
            ax2 = uiaxes(fig, 'Position', [250 150 200 200]);

            % 创建文本框和输入框
            uilabel(fig, 'Position', [20 50 50 20], 'Text', 'Size=');
            editField = uieditfield(fig, 'numeric', 'Position', [70 50 50 20]);

            % 创建下拉菜单用于选择Padding pattern
            uilabel(fig, 'Position', [320 50 100 20], 'Text', 'Padding pattern:');
            dropdown = uidropdown(fig, 'Position', [430 50 100 20], 'Items', {'zeros', 'symmetric'}, 'Value', 'zeros');

            % 创建滤波按钮
            button = uibutton(fig, 'push', 'Position', [200 50 80 20], 'Text', '滤波');
            button.ButtonPushedFcn = @(btn,event) filterImage(editField, dropdown, ax1, ax2);

            % 加载并显示原始图像
            img = app.current_img;
            imshow(img, 'Parent', ax1);
            title('原始图像','Parent', ax1);

            function filterImage(editField, dropdown, ax1, ax2)
                % 获取Size值
                sizeValue = editField.Value;

                % 获取Padding pattern
                paddingPattern = dropdown.Value;

                % 对原始图像进行最大值滤波
                filteredImage = ordfilt2(img, sizeValue*sizeValue, ones(sizeValue, sizeValue), paddingPattern);


                % 显示滤波后的图像
                imshow(filteredImage, 'Parent', ax2);
                title(['最大值滤波 Size=' num2str(sizeValue)],'Parent', ax2);

                % 更新 current_img
                app.current_img = filteredImage;
                % 在进行操作后，将新的图像添加到栈中
                app.imgStack{end+1} = app.current_img;
            end
        end

        % Menu selected function: Menu_34
        function Menu_34Selected(app, event)
            % 最小值滤波
            % 记录图像
            app.last_img=app.current_img;

            % 创建figure
            fig = uifigure('Name', 'Minvalue Filter Application');

            % 创建axes用于显示原始图像和滤波后的图像
            ax1 = uiaxes(fig, 'Position', [20 150 200 200]);
            ax2 = uiaxes(fig, 'Position', [250 150 200 200]);

            % 创建文本框和输入框
            uilabel(fig, 'Position', [20 50 50 20], 'Text', 'Size=');
            editField = uieditfield(fig, 'numeric', 'Position', [70 50 50 20]);

            % 创建下拉菜单用于选择Padding pattern
            uilabel(fig, 'Position', [320 50 100 20], 'Text', 'Padding pattern:');
            dropdown = uidropdown(fig, 'Position', [430 50 100 20], 'Items', {'zeros', 'symmetric'}, 'Value', 'zeros');

            % 创建滤波按钮
            button = uibutton(fig, 'push', 'Position', [200 50 80 20], 'Text', '滤波');
            button.ButtonPushedFcn = @(btn,event) filterImage(editField, dropdown, ax1, ax2);

            % 加载并显示原始图像
            img = app.current_img;
            imshow(img, 'Parent', ax1);
            title('原始图像','Parent', ax1);

            function filterImage(editField, dropdown, ax1, ax2)
                % 获取Size值
                sizeValue = editField.Value;

                % 获取Padding pattern
                paddingPattern = dropdown.Value;

                % 对原始图像进行最小值滤波
                filteredImage = ordfilt2(img,1, ones(sizeValue, sizeValue), paddingPattern);


                % 显示滤波后的图像
                imshow(filteredImage, 'Parent', ax2);
                title(['最小值滤波 Size=' num2str(sizeValue)],'Parent', ax2);

                % 更新 current_img
                app.current_img = filteredImage;
                % 在进行操作后，将新的图像添加到栈中
                app.imgStack{end+1} = app.current_img;
            end
        end

        % Menu selected function: Menu_35
        function Menu_35Selected(app, event)
            % 中值滤波
            % 记录图像
            app.last_img=app.current_img;

            % 创建figure
            fig = uifigure('Name', 'Median Filter');

            % 创建axes用于显示原始图像和滤波后的图像
            ax1 = uiaxes(fig, 'Position', [20 150 200 200]);
            ax2 = uiaxes(fig, 'Position', [250 150 200 200]);

            % 创建文本框和输入框
            uilabel(fig, 'Position', [20 50 50 20], 'Text', 'Size=');
            editField = uieditfield(fig, 'numeric', 'Position', [70 50 50 20]);

            % 创建下拉菜单用于选择Padding pattern
            uilabel(fig, 'Position', [320 50 100 20], 'Text', 'Padding pattern:');
            dropdown = uidropdown(fig, 'Position', [430 50 100 20], 'Items', {'zeros', 'symmetric'}, 'Value', 'zeros');

            % 创建滤波按钮
            button = uibutton(fig, 'push', 'Position', [200 50 80 20], 'Text', '滤波');
            button.ButtonPushedFcn = @(btn,event) filterImage(editField, dropdown, ax1, ax2);

            % 加载并显示原始图像
            img = app.current_img;
            imshow(img, 'Parent', ax1);
            title('原始图像','Parent', ax1);

            function filterImage(editField, dropdown, ax1, ax2)
                % 获取Size值
                sizeValue = editField.Value;

                % 获取Padding pattern
                paddingPattern = dropdown.Value;

                % 对原始图像进行中值滤波
                filteredImage = ordfilt2(img,(sizeValue*sizeValue+1)/2, ones(sizeValue, sizeValue), paddingPattern);


                % 显示滤波后的图像
                imshow(filteredImage, 'Parent', ax2);
                title(['中值滤波 Size=' num2str(sizeValue)],'Parent', ax2);

                % 更新 current_img
                app.current_img = filteredImage;
                % 在进行操作后，将新的图像添加到栈中
                app.imgStack{end+1} = app.current_img;
            end
        end

        % Menu selected function: Menu_36
        function Menu_36Selected(app, event)
            % 几何均值滤波
            % 记录图像
            app.last_img=app.current_img;

            % 创建figure
            fig = uifigure('Name', 'Geometric mean filter');

            % 创建axes用于显示原始图像和滤波后的图像
            ax1 = uiaxes(fig, 'Position', [20 150 200 200]);
            ax2 = uiaxes(fig, 'Position', [250 150 200 200]);

            % 创建文本框和输入框
            uilabel(fig, 'Position', [20 50 50 20], 'Text', 'Size=');
            editField = uieditfield(fig, 'numeric', 'Position', [70 50 50 20]);

            % 创建下拉菜单用于选择Padding pattern
            uilabel(fig, 'Position', [320 70 100 20], 'Text', 'Padding pattern:');
            dropdown = uidropdown(fig, 'Position', [430 70 100 20], 'Items', {'circular', 'replicate', 'symmetric'}, 'Value', 'replicate');

            % 创建滤波按钮
            button = uibutton(fig, 'push', 'Position', [200 50 80 20], 'Text', '滤波');
            button.ButtonPushedFcn = @(btn,event) filterImage(editField, dropdown, ax1, ax2);

            % 加载并显示原始图像
            img = app.current_img;
            imshow(img, 'Parent', ax1);
            title('原始图像','Parent', ax1);

            function filterImage(editField, dropdown, ax1, ax2)
                % 获取Size值
                sizeValue = editField.Value;

                % 获取Padding pattern
                paddingPattern = dropdown.Value;

                im2 = exp(imfilter(log(im2double(img)+eps),ones(sizeValue,sizeValue),paddingPattern)*(1/sizeValue/sizeValue)); % 几何均值滤波
                filteredImage = im2uint8(im2);   % 数据类型转换

                % 显示滤波后的图像
                imshow(filteredImage, 'Parent', ax2);
                title(['几何均值滤波 Size=' num2str(sizeValue)],'Parent', ax2);

                % 更新 current_img
                app.current_img = filteredImage;
                % 在进行操作后，将新的图像添加到栈中
                app.imgStack{end+1} = app.current_img;
            end
        end

        % Menu selected function: Menu_37
        function Menu_37Selected(app, event)
            % 谐波均值滤波
            % 记录图像
            app.last_img=app.current_img;

            % 创建figure
            fig = uifigure('Name', 'Harmonic mean filter');

            % 创建axes用于显示原始图像和滤波后的图像
            ax1 = uiaxes(fig, 'Position', [20 150 200 200]);
            ax2 = uiaxes(fig, 'Position', [250 150 200 200]);

            % 创建文本框和输入框
            uilabel(fig, 'Position', [20 50 50 20], 'Text', 'Size=');
            editField = uieditfield(fig, 'numeric', 'Position', [70 50 50 20]);

            % 创建下拉菜单用于选择Padding pattern
            uilabel(fig, 'Position', [320 70 100 20], 'Text', 'Padding pattern:');
            dropdown = uidropdown(fig, 'Position', [430 70 100 20], 'Items', {'circular', 'replicate', 'symmetric'}, 'Value', 'replicate');

            % 创建滤波按钮
            button = uibutton(fig, 'push', 'Position', [200 50 80 20], 'Text', '滤波');
            button.ButtonPushedFcn = @(btn,event) filterImage(editField, dropdown, ax1, ax2);

            % 加载并显示原始图像
            img = app.current_img;
            imshow(img, 'Parent', ax1);
            title('原始图像','Parent', ax1);

            function filterImage(editField, dropdown, ax1, ax2)
                % 获取Size值
                sizeValue = editField.Value;

                % 获取Padding pattern
                paddingPattern = dropdown.Value;

                im2 = sizeValue*sizeValue./imfilter(1./(im2double(img)+eps),ones(sizeValue,sizeValue),paddingPattern); % 谐波均值滤波
                filteredImage = im2uint8(im2);   % 数据类型转换

                % 显示滤波后的图像
                imshow(filteredImage, 'Parent', ax2);
                title(['谐波均值滤波 Size=' num2str(sizeValue)],'Parent', ax2);

                % 更新 current_img
                app.current_img = filteredImage;
                % 在进行操作后，将新的图像添加到栈中
                app.imgStack{end+1} = app.current_img;
            end
        end

        % Menu selected function: Menu_38
        function Menu_38Selected(app, event)
            % 逆谐波滤波
            % 记录图像
            app.last_img=app.current_img;
            % 创建figure
            fig = uifigure('Name', 'Inverse harmonic filter');

            % 创建axes用于显示原始图像和滤波后的图像
            ax1 = uiaxes(fig, 'Position', [20 150 200 200]);
            ax2 = uiaxes(fig, 'Position', [250 150 200 200]);

            % 创建文本框和输入框
            uilabel(fig, 'Position', [20 50 50 20], 'Text', 'Size=');
            sizeField = uieditfield(fig, 'numeric', 'Position', [70 50 40 20]);

            uilabel(fig, 'Position', [130 50 50 20], 'Text', 'Q=');
            Q = uieditfield(fig, 'numeric', 'Position', [180 50 50 20]);

            % 创建下拉菜单用于选择Padding pattern
            uilabel(fig, 'Position', [250 50 100 20], 'Text', 'Padding pattern:');
            dropdown = uidropdown(fig, 'Position', [350 50 100 20], 'Items', {'circular', 'replicate', 'symmetric'}, 'Value', 'replicate');

            % 创建滤波按钮
            button = uibutton(fig, 'push', 'Position', [470 50 50 20], 'Text', '滤波');
            button.ButtonPushedFcn = @(btn,event) filterImage(sizeField, Q, dropdown, ax1, ax2);

            % 加载并显示原始图像
            img = app.current_img;
            imshow(img, 'Parent', ax1);
            title('原始图像','Parent', ax1);
            function filterImage(sizeField, Q, dropdown, ax1, ax2)
                % 获取Size值和Sigma值
                sizeValue = sizeField.Value;
                q = Q.Value;

                % 获取Padding pattern
                paddingPattern = dropdown.Value;

                % 获取原始图像
                originalImage = getimage(ax1);

                im = imfilter(im2double(img).^(q+1),ones(sizeValue,sizeValue),paddingPattern);
                im = im./(imfilter(im2double(img).^q,ones(sizeValue,sizeValue),paddingPattern)+eps); % 逆谐波均值滤波
                filteredImage = im2uint8(im); % 类型转换

                % 显示滤波后的图像
                imshow(filteredImage, 'Parent', ax2);
                title(['逆谐波滤波 Size=' num2str(sizeValue) 'Q=' num2str(q)],'Parent', ax2);

                % 更新 current_img
                app.current_img = filteredImage;
                % 在进行操作后，将新的图像添加到栈中
                app.imgStack{end+1} = app.current_img;
            end
        end

        % Menu selected function: Menu_39
        function Menu_39Selected(app, event)
            if isempty(app.img)
                uialert(app.UIFigure, '请先打开一个图像', '错误');
                return;
            end
        end

        % Menu selected function: Menu_40
        function Menu_40Selected(app, event)
            
        end

        % Menu selected function: Menu_51
        function Menu_51Selected(app, event)
            % 高斯噪声
            % 记录图像
            app.last_img=app.current_img;
            % 创建figure
            fig = uifigure('Name', 'Gausssian noise');

            % 创建axes用于显示原始图像和滤波后的图像
            ax1 = uiaxes(fig, 'Position', [20 150 200 200]);
            ax2 = uiaxes(fig, 'Position', [230 150 200 200]);

            % 创建文本框和输入框
            uilabel(fig, 'Position', [20 50 50 20], 'Text', 'm=');
            m = uieditfield(fig, 'numeric', 'Position', [70 50 40 20]);

            uilabel(fig, 'Position', [130 50 50 20], 'Text', 'Sigma=');
            Sigma = uieditfield(fig, 'numeric', 'Position', [180 50 50 20],'Value',0.01);

            % % 创建下拉菜单用于选择Padding pattern
            % uilabel(fig, 'Position', [250 50 100 20], 'Text', 'Padding pattern:');
            % dropdown = uidropdown(fig, 'Position', [350 50 100 20], 'Items', {'circular', 'replicate', 'symmetric'}, 'Value', 'replicate');

            % 创建滤波按钮
            button = uibutton(fig, 'push', 'Position', [300 50 50 20], 'Text', '噪声');
            button.ButtonPushedFcn = @(btn,event) filterImage(m,Sigma, ax1, ax2);

            % 加载并显示原始图像
            img = app.current_img;
            imshow(img, 'Parent', ax1);
            title('原始图像','Parent', ax1);
            function filterImage(m,Sigma, ax1, ax2)
                % 获取Size值和Sigma值
                mValue = m.Value;
                SigmaValue = Sigma.Value;

                % % 获取Padding pattern
                % paddingPattern = dropdown.Value;
                noise_img=imnoise(img,'gaussian',mValue,SigmaValue);

                % 显示滤波后的图像
                imshow(noise_img, 'Parent', ax2);
                title(['高斯噪声 m=' num2str(mValue) 'Sigma=' num2str(SigmaValue)],'Parent', ax2);

                app.estimated_nsr=SigmaValue / var(im2double(img(:)));

                % 更新 current_img
                app.current_img = noise_img;
                % 在进行操作后，将新的图像添加到栈中
                app.imgStack{end+1} = app.current_img;
            end
        end

        % Menu selected function: Menu_52
        function Menu_52Selected(app, event)
            % 泊松噪声
            % 记录图像
            app.last_img=app.current_img;
            % 创建figure
            fig = uifigure('Name', 'Poisson noise');

            % 创建axes用于显示原始图像和滤波后的图像
            ax1 = uiaxes(fig, 'Position', [20 150 200 200]);
            ax2 = uiaxes(fig, 'Position', [230 150 200 200]);

            % % 创建文本框和输入框
            % uilabel(fig, 'Position', [20 50 50 20], 'Text', 'm=');
            % m = uieditfield(fig, 'numeric', 'Position', [70 50 40 20]);
            % 
            % uilabel(fig, 'Position', [130 50 50 20], 'Text', 'Sigma=');
            % Sigma = uieditfield(fig, 'numeric', 'Position', [180 50 50 20]);

            % % 创建下拉菜单用于选择Padding pattern
            % uilabel(fig, 'Position', [250 50 100 20], 'Text', 'Padding pattern:');
            % dropdown = uidropdown(fig, 'Position', [350 50 100 20], 'Items', {'circular', 'replicate', 'symmetric'}, 'Value', 'replicate');

            % 创建滤波按钮
            button = uibutton(fig, 'push', 'Position', [250 50 50 20], 'Text', '噪声');
            button.ButtonPushedFcn = @(btn,event) filterImage(ax1, ax2);

            % 加载并显示原始图像
            img = app.current_img;
            imshow(img, 'Parent', ax1);
            title('原始图像','Parent', ax1);
            function filterImage( ax1, ax2)
                % % 获取Size值和Sigma值
                % mValue = m.Value;
                % SigmaValue = Sigma.Value;

                % % 获取Padding pattern
                % paddingPattern = dropdown.Value;
                noise_img=imnoise(img,'poisson');

                % 显示滤波后的图像
                imshow(noise_img, 'Parent', ax2);
                title(['泊松噪声'],'Parent', ax2);

                % 更新 current_img
                app.current_img = noise_img;
                % 在进行操作后，将新的图像添加到栈中
                app.imgStack{end+1} = app.current_img;
            end
        end

        % Menu selected function: Menu_53
        function Menu_53Selected(app, event)
            % 椒盐噪声
            % 记录图像
            app.last_img=app.current_img;
            % 创建figure
            fig = uifigure('Name', 'Salt & pepper noise');

            % 创建axes用于显示原始图像和滤波后的图像
            ax1 = uiaxes(fig, 'Position', [20 150 200 200]);
            ax2 = uiaxes(fig, 'Position', [230 150 200 200]);

            % 创建文本框和输入框
            uilabel(fig, 'Position', [120 50 50 20], 'Text', 'd=');
            d = uieditfield(fig, 'numeric', 'Position', [170 50 40 20],'Value',0.05);

            % uilabel(fig, 'Position', [130 50 50 20], 'Text', 'Sigma=');
            % Sigma = uieditfield(fig, 'numeric', 'Position', [180 50 50 20]);

            % % 创建下拉菜单用于选择Padding pattern
            % uilabel(fig, 'Position', [250 50 100 20], 'Text', 'Padding pattern:');
            % dropdown = uidropdown(fig, 'Position', [350 50 100 20], 'Items', {'circular', 'replicate', 'symmetric'}, 'Value', 'replicate');

            % 创建滤波按钮
            button = uibutton(fig, 'push', 'Position', [300 50 50 20], 'Text', '噪声');
            button.ButtonPushedFcn = @(btn,event) filterImage(d, ax1, ax2);

            % 加载并显示原始图像
            img = app.current_img;
            imshow(img, 'Parent', ax1);
            title('原始图像','Parent', ax1);
            function filterImage(d, ax1, ax2)
                % 获取Size值和Sigma值
                dValue = d.Value;
                %SigmaValue = Sigma.Value;

                % % 获取Padding pattern
                % paddingPattern = dropdown.Value;
                noise_img=imnoise(img,'salt & pepper',dValue);

                % 显示滤波后的图像
                imshow(noise_img, 'Parent', ax2);
                title(['椒盐噪声 d=' num2str(dValue)],'Parent', ax2);

                % 更新 current_img
                app.current_img = noise_img;
                % 在进行操作后，将新的图像添加到栈中
                app.imgStack{end+1} = app.current_img;
            end
        end

        % Menu selected function: Menu_54
        function Menu_54Selected(app, event)
            % 均匀噪声
            % 记录图像
            app.last_img=app.current_img;
            % 创建figure
            fig = uifigure('Name', 'Speckle noise');

            % 创建axes用于显示原始图像和滤波后的图像
            ax1 = uiaxes(fig, 'Position', [20 150 200 200]);
            ax2 = uiaxes(fig, 'Position', [230 150 200 200]);

            % 创建文本框和输入框
            uilabel(fig, 'Position', [120 50 100 20], 'Text', 'var_speckle=');
            var_speckle = uieditfield(fig, 'numeric', 'Position', [220 50 40 20],'Value',0.05);

            % uilabel(fig, 'Position', [130 50 50 20], 'Text', 'Sigma=');
            % Sigma = uieditfield(fig, 'numeric', 'Position', [180 50 50 20]);

            % % 创建下拉菜单用于选择Padding pattern
            % uilabel(fig, 'Position', [250 50 100 20], 'Text', 'Padding pattern:');
            % dropdown = uidropdown(fig, 'Position', [350 50 100 20], 'Items', {'circular', 'replicate', 'symmetric'}, 'Value', 'replicate');

            % 创建滤波按钮
            button = uibutton(fig, 'push', 'Position', [300 50 50 20], 'Text', '噪声');
            button.ButtonPushedFcn = @(btn,event) filterImage(var_speckle, ax1, ax2);

            % 加载并显示原始图像
            img = app.current_img;
            imshow(img, 'Parent', ax1);
            title('原始图像','Parent', ax1);
            function filterImage(var_speckle, ax1, ax2)
                % 获取Size值和Sigma值
                var_speckleValue = var_speckle.Value;
                %SigmaValue = Sigma.Value;

                % % 获取Padding pattern
                % paddingPattern = dropdown.Value;
                noise_img=imnoise(img,'speckle',var_speckleValue);

                % 显示滤波后的图像
                imshow(noise_img, 'Parent', ax2);
                title(['均匀噪声 varspeckle=' num2str(var_speckleValue)],'Parent', ax2);

                % 更新 current_img
                app.current_img = noise_img;
                % 在进行操作后，将新的图像添加到栈中
                app.imgStack{end+1} = app.current_img;
            end
        end

        % Menu selected function: Menu_41
        function Menu_41Selected(app, event)
            % 频谱
            % 记录图像
            app.last_img=app.current_img;
            % 创建figure
            fig = uifigure('Name', 'Fourier spectrum');

            % 创建axes用于显示原始图像和滤波后的图像
            ax1 = uiaxes(fig, 'Position', [20 20 400 400]);
            img=app.current_img;
            img=im2double(img);
            %变换
            F=fft2(img);
            Fshift = fftshift(F);
            app.Fourier_spectrum = Fshift;
            imshow(log(1+abs(Fshift)), [],'Parent',ax1), title('傅立叶变换后的频谱');
        end

        % Menu selected function: Menu_42
        function Menu_42Selected(app, event)
            % 大气湍流
            % 记录图像
            app.last_img=app.current_img;
            % 创建figure
            fig = uifigure('Name', 'Atmospheric turbulence');

            % 创建axes用于显示原始图像和滤波后的图像
            ax1 = uiaxes(fig, 'Position', [20 150 200 200]);
            ax2 = uiaxes(fig, 'Position', [230 150 200 200]);
            img=app.current_img;
            imshow(img,'Parent',ax1);title('原图','Parent',ax1);

            [H,im_turbulent,~] = atmosph(img);
            app.degenerate_transfer=H;
            imshow(im_turbulent,'Parent',ax2);title('大气湍流','Parent',ax2);

            % 更新 current_img
            app.current_img = im_turbulent;
            % 在进行操作后，将新的图像添加到栈中
            app.imgStack{end+1} = app.current_img;
        end

        % Menu selected function: Menu_43
        function Menu_43Selected(app, event)
            % 运动模糊
            % 记录图像
            app.last_img=app.current_img;
            % 创建figure
            fig = uifigure('Name', 'Motion blur');

            % 创建axes用于显示原始图像和滤波后的图像
            ax1 = uiaxes(fig, 'Position', [20 150 200 200]);
            ax2 = uiaxes(fig, 'Position', [230 150 200 200]);
             % 创建文本框和输入框
            uilabel(fig, 'Position', [50 50 50 20], 'Text', 'Theta=');
            Theta = uieditfield(fig, 'numeric', 'Position', [100 50 40 20],'Value',45);

            uilabel(fig, 'Position', [150 50 50 20], 'Text', 'Len=');
            Len = uieditfield(fig, 'numeric', 'Position', [200 50 50 20],'Value',10);

            % 创建滤波按钮
            button = uibutton(fig, 'push', 'Position', [350 50 50 20], 'Text', '模糊');
            button.ButtonPushedFcn = @(btn,event) filterImage(Theta, Len, ax1, ax2);

            % 加载并显示原始图像
            img = app.current_img;
            imshow(img, 'Parent', ax1);
            title('原始图像','Parent', ax1);
            function filterImage(Theta, Len, ax1, ax2)
                % 获取Size值和Sigma值
                Theta = Theta.Value;
                Len = Len.Value;

                H = fspecial('motion',Len,Theta);
                img_d=imfilter(im2double(img),H,'conv', 'circular');
                app.img_d=img_d;
                MotionBlur = im2uint8(img_d);
                [rows,cols] = size(img);
                app.degenerate = H;
                app.degenerate_transfer = fftshift(fft2(padarray(H, [rows - size(H, 1), cols - size(H, 2)], 'post')));

                % 显示滤波后的图像
                imshow(MotionBlur , 'Parent', ax2);
                title(['运动模糊 Theta=' num2str(Theta) 'Len=' num2str(Len)],'Parent', ax2);

                % 更新 current_img
                app.current_img = MotionBlur ;
                % 在进行操作后，将新的图像添加到栈中
                app.imgStack{end+1} = app.current_img;
            end
        end

        % Menu selected function: Menu_45
        function Menu_45Selected(app, event)
            % 低通滤波
            % 记录图像
            app.last_img=app.current_img;
            % 创建figure
            fig = uifigure('Name', 'Low-pass filter');

            % 创建axes用于显示原始图像和滤波后的图像
            ax1 = uiaxes(fig, 'Position', [20 150 200 200]);
            ax2 = uiaxes(fig, 'Position', [230 150 200 200]);

            % 创建文本框和输入框
            uilabel(fig, 'Position', [150 50 30 20], 'Text', 'd=');
            d = uieditfield(fig, 'numeric', 'Position', [180 50 40 20],'Value',40);

            % uilabel(fig, 'Position', [130 50 50 20], 'Text', 'Sigma=');
            % Sigma = uieditfield(fig, 'numeric', 'Position', [180 50 50 20]);

            % % 创建下拉菜单用于选择Padding pattern
            % uilabel(fig, 'Position', [250 50 100 20], 'Text', 'Padding pattern:');
            % dropdown = uidropdown(fig, 'Position', [350 50 100 20], 'Items', {'circular', 'replicate', 'symmetric'}, 'Value', 'replicate');

            % 创建滤波按钮
            button = uibutton(fig, 'push', 'Position', [300 50 50 20], 'Text', '滤波');
            button.ButtonPushedFcn = @(btn,event) filterImage(d, ax1, ax2);

            % 加载并显示原始图像
            img = app.current_img;
            imshow(img, 'Parent', ax1);
            title('原始图像','Parent', ax1);
            function filterImage(d, ax1, ax2)
                % 获取Size值和Sigma值
                dValue = d.Value;

                if isempty(app.Fourier_spectrum)
                    %变换
                    F=fft2(img);
                    Fshift = fftshift(F);
                    app.Fourier_spectrum=Fshift;
                else
                    Fshift=app.Fourier_spectrum;
                end

                [P,Q]=size(img);
                u=-P/2:(P/2-1);
                v=-Q/2:(Q/2-1);
                [U,V]=meshgrid(v,u);% meshgrid(u,v)的作用是分别产生以向量u为行，向量v为列的两个大小相同的矩阵

                mask_low=(sqrt(U.^2+V.^2)<=dValue); % 创建一个低通滤波器
                mask_fshift = Fshift .* mask_low; % 应用低通滤波器
                f_ishift = ifftshift(mask_fshift); % 将零频率分量移动回频谱的左上角
                img_back = im2uint8(mat2gray(ifft2(f_ishift))); % 对图像进行逆傅里叶变换

                % 显示滤波后的图像
                imshow(img_back, 'Parent', ax2);
                title(['低通滤波 d=' num2str(dValue)],'Parent', ax2);

                % 更新 current_img
                app.current_img = img_back;
                % 在进行操作后，将新的图像添加到栈中
                app.imgStack{end+1} = app.current_img;
            end
        end

        % Menu selected function: Menu_46
        function Menu_46Selected(app, event)
            % 高通滤波
            % 记录图像
            app.last_img=app.current_img;
            % 创建figure
            fig = uifigure('Name', 'High-pass filter');

            % 创建axes用于显示原始图像和滤波后的图像
            ax1 = uiaxes(fig, 'Position', [20 150 200 200]);
            ax2 = uiaxes(fig, 'Position', [230 150 200 200]);

            % 创建文本框和输入框
            uilabel(fig, 'Position', [150 50 30 20], 'Text', 'd=');
            d = uieditfield(fig, 'numeric', 'Position', [180 50 40 20],'Value',40);

            % uilabel(fig, 'Position', [130 50 50 20], 'Text', 'Sigma=');
            % Sigma = uieditfield(fig, 'numeric', 'Position', [180 50 50 20]);

            % % 创建下拉菜单用于选择Padding pattern
            % uilabel(fig, 'Position', [250 50 100 20], 'Text', 'Padding pattern:');
            % dropdown = uidropdown(fig, 'Position', [350 50 100 20], 'Items', {'circular', 'replicate', 'symmetric'}, 'Value', 'replicate');

            % 创建滤波按钮
            button = uibutton(fig, 'push', 'Position', [300 50 50 20], 'Text', '滤波');
            button.ButtonPushedFcn = @(btn,event) filterImage(d, ax1, ax2);

            % 加载并显示原始图像
            img = app.current_img;
            imshow(img, 'Parent', ax1);
            title('原始图像','Parent', ax1);
            function filterImage(d, ax1, ax2)
                % 获取Size值和Sigma值
                dValue = d.Value;

                if isempty(app.Fourier_spectrum)
                    %变换
                    F=fft2(img);
                    Fshift = fftshift(F);
                    app.Fourier_spectrum=Fshift;
                else
                    Fshift=app.Fourier_spectrum;
                end

                [P,Q]=size(img);
                u=-P/2:(P/2-1);
                v=-Q/2:(Q/2-1);
                [U,V]=meshgrid(v,u);% meshgrid(u,v)的作用是分别产生以向量u为行，向量v为列的两个大小相同的矩阵

                mask_low=(sqrt(U.^2+V.^2)>dValue); % 创建一个高通滤波器
                mask_fshift = Fshift .* mask_low; % 应用高通滤波器
                f_ishift = ifftshift(mask_fshift); % 将零频率分量移动回频谱的左上角
                img_back = im2uint8(mat2gray(ifft2(f_ishift))); % 对图像进行逆傅里叶变换

                % 显示滤波后的图像
                imshow(img_back, 'Parent', ax2);
                title(['高通滤波 d=' num2str(dValue)],'Parent', ax2);

                % 更新 current_img
                app.current_img = img_back;
                % 在进行操作后，将新的图像添加到栈中
                app.imgStack{end+1} = app.current_img;
            end
        end

        % Menu selected function: Menu_47
        function Menu_47Selected(app, event)
            % 同态滤波
            % 记录图像
            app.last_img=app.current_img;
            % 创建figure
            fig = uifigure('Name', 'Homomorphic filter');

            % 创建axes用于显示原始图像和滤波后的图像
            ax1 = uiaxes(fig, 'Position', [20 150 200 200]);
            ax2 = uiaxes(fig, 'Position', [230 150 200 200]);

            % 创建文本框和输入框
            uilabel(fig, 'Position', [70 50 30 20], 'Text', 'd=');
            d = uieditfield(fig, 'numeric', 'Position', [90 50 30 20],'Value',10);

            uilabel(fig, 'Position', [130 50 80 20], 'Text', 'Gamma_H=');
            Gamma_H = uieditfield(fig, 'numeric', 'Position', [200 50 30 20],'Value',1.0);

            uilabel(fig, 'Position', [240 50 80 20], 'Text', 'Gamma_L=');
            Gamma_L = uieditfield(fig, 'numeric', 'Position', [310 50 30 20],'Value',0.4);

            uilabel(fig, 'Position', [350 50 30 20], 'Text', 'c=');
            c = uieditfield(fig, 'numeric', 'Position', [370 50 30 20],'Value',5);

            % % 创建下拉菜单用于选择Padding pattern
            % uilabel(fig, 'Position', [250 50 100 20], 'Text', 'Padding pattern:');
            % dropdown = uidropdown(fig, 'Position', [350 50 100 20], 'Items', {'circular', 'replicate', 'symmetric'}, 'Value', 'replicate');

            % 创建滤波按钮
            button = uibutton(fig, 'push', 'Position', [420 50 50 20], 'Text', '滤波');
            button.ButtonPushedFcn = @(btn,event) filterImage(d,Gamma_H,Gamma_L,c, ax1, ax2);

            % 加载并显示原始图像
            img = app.current_img;
            imshow(img, 'Parent', ax1);
            title('原始图像','Parent', ax1);
            function filterImage(d,Gamma_H,Gamma_L,c, ax1, ax2)
                % 获取Size值和Sigma值
                D0 = d.Value;
                gamma_H=Gamma_H.Value;
                gamma_L=Gamma_L.Value;
                cValue=c.Value;

                % 同态滤波
                C=double(img);
                % 获取图像的大小
                [M, N] = size(C);
                % 计算填充后的尺寸
                P = 2*M;
                Q = 2*N;
                % 对图像进行零填充
                padded_img = padarray(C, [M N], 'post');
                % 对图像进行对数变换
                log_img = log(1 + padded_img);
                D0=10;
                gamma_H=1.0;
                gamma_L=0.4;
                [X, Y] = meshgrid(1:Q,1:P);
                centerX = ceil(Q/2);
                centerY = ceil(P/2);
                gaussianNumerator = (X - centerX).^2 + (Y - centerY).^2;
                H=(gamma_H-gamma_L)*(exp(-cValue*gaussianNumerator/D0^2))+gamma_L;
                %H = fftshift(H); % 将零频率分量移动到频谱的中心
                If = fft2(log_img, P, Q); % 对图像进行傅里叶变换
                Iout = ifft2(H.*If); % 对图像进行逆傅里叶变换
                Iout = Iout(1:M, 1:N); % 裁剪回原始尺寸
                Ihmf = real(exp(Iout) - 1);
                img_back = im2uint8(mat2gray(Ihmf)); % 对图像进行逆傅里叶变换

                % 显示滤波后的图像
                imshow(img_back, 'Parent', ax2);
                title('同态滤波','Parent', ax2);

                % 更新 current_img
                app.current_img = img_back;
                % 在进行操作后，将新的图像添加到栈中
                app.imgStack{end+1} = app.current_img;
            end
        end

        % Menu selected function: Menu_48
        function Menu_48Selected(app, event)
            % 陷波滤波
            % 记录图像
            app.last_img=app.current_img;
            % 创建figure
            fig = uifigure('Name', 'Trapping waves filter');

            % 创建axes用于显示原始图像和滤波后的图像
            ax1 = uiaxes(fig, 'Position', [20 150 200 200]);
            ax2 = uiaxes(fig, 'Position', [230 150 200 200]);

            % 创建文本框和输入框
            uilabel(fig, 'Position', [150 50 30 20], 'Text', 'd=');
            d = uieditfield(fig, 'numeric', 'Position', [180 50 40 20],'Value',20);

            uilabel(fig, 'Position', [230 50 80 20], 'Text', 'Threshold=');
            Threshold = uieditfield(fig, 'numeric', 'Position', [310 50 50 20],'Value',5e-6);

            % % 创建下拉菜单用于选择Padding pattern
            % uilabel(fig, 'Position', [250 50 100 20], 'Text', 'Padding pattern:');
            % dropdown = uidropdown(fig, 'Position', [350 50 100 20], 'Items', {'circular', 'replicate', 'symmetric'}, 'Value', 'replicate');

            % 创建滤波按钮
            button = uibutton(fig, 'push', 'Position', [360 50 50 20], 'Text', '滤波');
            button.ButtonPushedFcn = @(btn,event) filterImage(d,Threshold, ax1, ax2);

            % 加载并显示原始图像
            img = app.current_img;
            imshow(img, 'Parent', ax1);
            title('原始图像','Parent', ax1);
            function filterImage(d,Threshold, ax1, ax2)
                % 获取Size值和Sigma值
                dValue = d.Value;
                ThresholdV=Threshold.Value;
                if isempty(app.Fourier_spectrum)
                    %变换
                    F=fft2(im2double(img));
                    Fshift = fftshift(F);
                    app.Fourier_spectrum=Fshift;
                else
                    Fshift=app.Fourier_spectrum;
                end

                % 使用功率谱密度估计来识别噪声频率
                psd = abs(Fshift).^2;
                noise_threshold = max(psd(:)) * ThresholdV; % 设定一个阈值来识别噪声
                noise_freq = psd > noise_threshold;

                % 设计陷波滤波器来滤除噪声频率
                filter = ones(size(Fshift));
                filter(noise_freq) = 0;
                % 构造n阶巴特沃兹陷波滤波器
                [M,N,~] = size(img);
                u = 0:(M-1);
                v = 0:(N-1);
                [V,U] = meshgrid(v,u);
                filter(sqrt((V-N/2).^2+(U-M/2).^2)<=dValue)=1;

                % 应用陷波滤波器到频域图像
                fshift_filtered = Fshift .* filter;

                % 进行傅里叶逆变换，得到去除噪声的图像
                g = real(ifft2(ifftshift(fshift_filtered)));

                img_back=im2uint8(g);

                % 显示滤波后的图像
                imshow(img_back, 'Parent', ax2);
                title(['陷波滤波 d=' num2str(dValue)],'Parent', ax2);

                % 更新 current_img
                app.current_img = img_back;
                % 在进行操作后，将新的图像添加到栈中
                app.imgStack{end+1} = app.current_img;
            end
        end

        % Menu selected function: Menu_49
        function Menu_49Selected(app, event)
            % 逆滤波
            if isempty(app.degenerate_transfer)
                uialert(app.UIFigure, '请先进行退化操作', '错误');
                return;
            else
            % 记录图像
            app.last_img=app.current_img;
            % 创建figure
            fig = uifigure('Name', 'Inverse filter');

            % 创建axes用于显示原始图像和滤波后的图像
            ax1 = uiaxes(fig, 'Position', [20 150 200 200]);
            ax2 = uiaxes(fig, 'Position', [230 150 200 200]);

            % 创建文本框和输入框
            uilabel(fig, 'Position', [150 50 30 20], 'Text', 'd=');
            d = uieditfield(fig, 'numeric', 'Position', [180 50 40 20],'Value',40);

            % uilabel(fig, 'Position', [130 50 50 20], 'Text', 'Sigma=');
            % Sigma = uieditfield(fig, 'numeric', 'Position', [180 50 50 20]);

            % % 创建下拉菜单用于选择Padding pattern
            % uilabel(fig, 'Position', [250 50 100 20], 'Text', 'Padding pattern:');
            % dropdown = uidropdown(fig, 'Position', [350 50 100 20], 'Items', {'circular', 'replicate', 'symmetric'}, 'Value', 'replicate');

            % 创建滤波按钮
            button = uibutton(fig, 'push', 'Position', [300 50 50 20], 'Text', '滤波');
            button.ButtonPushedFcn = @(btn,event) filterImage(d, ax1, ax2);

            % 加载并显示原始图像
            img = app.current_img;
            imshow(img, 'Parent', ax1);
            title('原始图像','Parent', ax1);
            end
            function filterImage(d, ax1, ax2)
                % 获取Size值和Sigma值
                dValue = d.Value;

                if isempty(app.Fourier_spectrum)
                    %变换
                    F=fft2(img);
                    Fshift = fftshift(F);
                    app.Fourier_spectrum=Fshift;
                else
                    Fshift=app.Fourier_spectrum;
                end

                H=app.degenerate_transfer;
                [~,img_back]=my_inverse(img,H,dValue);

                % 显示滤波后的图像
                imshow(img_back, 'Parent', ax2);
                title(['逆滤波 d=' num2str(dValue)],'Parent', ax2);

                % 更新 current_img
                app.current_img = img_back;
                % 在进行操作后，将新的图像添加到栈中
                app.imgStack{end+1} = app.current_img;
            end
        end

        % Menu selected function: Menu_50
        function Menu_50Selected(app, event)
             % 维纳滤波

            if isempty(app.degenerate_transfer)
                uialert(app.UIFigure, '请先进行退化操作', '错误');
                return;
            else
            % 记录图像
            app.last_img=app.current_img;
            % 创建figure
            fig = uifigure('Name', 'Winner filter');

            % 创建axes用于显示原始图像和滤波后的图像
            ax1 = uiaxes(fig, 'Position', [20 150 200 200]);
            ax2 = uiaxes(fig, 'Position', [230 150 200 200]);

            % 创建文本框和输入框
            if isempty(app.estimated_nsr)
                estimated_nsr=0;
            else
                estimated_nsr=app.estimated_nsr;
            end
            uilabel(fig, 'Position', [150 50 30 20], 'Text', 'nsr=');
            nsr = uieditfield(fig, 'numeric', 'Position', [180 50 40 20],'Value',estimated_nsr);

            % uilabel(fig, 'Position', [130 50 50 20], 'Text', 'Sigma=');
            % Sigma = uieditfield(fig, 'numeric', 'Position', [180 50 50 20]);

            % % 创建下拉菜单用于选择Padding pattern
            % uilabel(fig, 'Position', [250 50 100 20], 'Text', 'Padding pattern:');
            % dropdown = uidropdown(fig, 'Position', [350 50 100 20], 'Items', {'circular', 'replicate', 'symmetric'}, 'Value', 'replicate');

            % 创建滤波按钮
            button = uibutton(fig, 'push', 'Position', [300 50 50 20], 'Text', '滤波');
            button.ButtonPushedFcn = @(btn,event) filterImage(nsr, ax1, ax2);

            % 加载并显示原始图像
            img = im2double(app.current_img);
            imshow(img, 'Parent', ax1);
            title('原始图像','Parent', ax1);
            end
            function filterImage(nsr, ax1, ax2)
                % 获取Size值和Sigma值
                nsrValue = nsr.Value;

                if isempty(app.Fourier_spectrum)
                    %变换
                    F=fft2(img);
                    Fshift = fftshift(F);
                    app.Fourier_spectrum=Fshift;
                else
                    Fshift=app.Fourier_spectrum;
                end

                img=app.img_d;
                H=app.degenerate;
                img_back=deconvwnr(img,H,nsrValue);

                % 显示滤波后的图像
                imshow(img_back, 'Parent', ax2);
                title(['维纳滤波 nsr=' num2str(nsrValue)],'Parent', ax2);

                % 更新 current_img
                app.current_img = im2uint8(img_back);
                % 在进行操作后，将新的图像添加到栈中
                app.imgStack{end+1} = app.current_img;
            end
        end

        % Menu selected function: rmseMenu
        function rmseMenuSelected(app, event)
            %计算两幅图像的rmse
            % 使用 uigetfile 函数打开文件选择对话框
            [file,path] = uigetfile({'*.jpg;*.png;*.bmp;*.tif','All Image Files';...
                '*.*','All Files' },'Select an Image');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                % 读取选择的图像并存储为 img_new
                img_new = imread(fullfile(path,file));

                % 显示新图像
                figure;
                imshow(img_new);
                title('Selected Image');

                % 计算两幅图像的 RMSE
                rmseValue = calculateRMSE(app.img, img_new);

                % 在图片下方显示 RMSE 值
                % annotation('textbox', [0.5, 0.1, 0.1, 0.1], 'String', ['RMSE: ', num2str(rmseValue)],...
                %     'HorizontalAlignment', 'center', 'EdgeColor', 'none');
                xlabel(['RMSE: ', num2str(rmseValue)]);
            end
            % 自定义函数 calculateRMSE 用于计算 RMSE
            function rmseValue = calculateRMSE(img1, img2)
                % 确保两幅图像大小相同
                if size(img1) ~= size(img2)
                    error('The images must be of the same size.');
                end

                % 转换图像数据类型为 double
                img1 = double(img1);
                img2 = double(img2);

                % 计算 RMSE
                rmseValue = sqrt(mean((img1(:) - img2(:)).^2));
            end
        end

        % Menu selected function: huffmanMenu
        function huffmanMenuSelected(app, event)
            % Huffman编码
            % 显示原图像
            img=app.current_img;
            figure;
            imshow(img);
            title('Original Image');

            SIG=img(:);
            T=imhist(img);
            TRatio = T/sum(T);         %每一个灰度出现比率
            TRatioNO = find(TRatio~=-1);%给存在的灰度编号

            % 哈夫曼编码
            [dict,avglen] = huffmandict(TRatioNO,TRatio); %哈夫曼取字典函数
            hcode = huffmanenco(SIG,dict);        %哈夫曼编码
            app.huffmanDict=dict;

            %图像熵
            entropy_B=-sum(TRatio.*log2(TRatio+eps));

            % 编码效率通常定义为图像熵与平均码长之比
            efficiency_B = entropy_B / avglen;

            % 在图片下方显示计算结果
            set(gcf, 'Position', get(gcf, 'Position') + [0 -50 100 50]); % 调整窗口大小以显示文本
            uicontrol('Style', 'text', 'Position', [20 20 120 20],...
                'String', ['Entropy: ', num2str(entropy_B)]);
            uicontrol('Style', 'text', 'Position', [140 20 140 20],...
                'String', ['Average Code Length: ', num2str(avglen)]);
            uicontrol('Style', 'text', 'Position', [280 20 150 20],...
                'String', ['Coding Efficiency: ', num2str(efficiency_B)]);
        end

        % Menu selected function: Menu_55
        function Menu_55Selected(app, event)
            if isempty(app.img)
                uialert(app.UIFigure, '请先打开一个图像', '错误');
                return;
            end
        end

        % Menu selected function: Menu_56
        function Menu_56Selected(app, event)
            % 数学形态学
            if isempty(app.img)
                uialert(app.UIFigure, '请先打开一个图像', '错误');
                return;
            end
            
        end

        % Menu selected function: Menu_58
        function Menu_58Selected(app, event)
            % 形态学处理
            % 记录图像
            app.last_img=app.current_img;

            % 创建一个新窗口
            morphologyFig = uifigure('Name', '数学形态学处理', 'NumberTitle', 'off');
            set(morphologyFig, 'Position', [800, 500, 500, 300]); % 设置窗口位置和大小

            % 创建axes用于显示原始图像和滤波后的图像
            ax1 = uiaxes(morphologyFig, 'Position', [20 100 200 200]);
            ax2 = uiaxes(morphologyFig, 'Position', [250 100 200 200]);

            % 创建文本框和输入框
            uilabel(morphologyFig, 'Position', [20 50 50 20], 'Text', 'len=');
            len = uieditfield(morphologyFig, 'numeric', 'Position', [70 50 50 20]);
            % 创建文本框和输入框
            uilabel(morphologyFig, 'Position', [120 50 50 20], 'Text', 'wid=');
            wid = uieditfield(morphologyFig, 'numeric', 'Position', [170 50 50 20]);

            % 创建下拉菜单用于选择形态学操作
            uilabel(morphologyFig, 'Position', [220 50 100 20], 'Text', 'Padding pattern:');
            dropdown = uidropdown(morphologyFig, 'Position', [320 50 80 20], 'Items', {'erode', 'dilate', 'open', 'close'}, 'Value', 'erode');

            % 创建滤波按钮
            button = uibutton(morphologyFig, 'push', 'Position', [400 50 80 20], 'Text', '处理');
            button.ButtonPushedFcn = @(btn,event) filterImage(len,wid, dropdown, ax1, ax2);

            % 加载并显示原始图像
            img = app.current_img;
            imshow(img, 'Parent', ax1);
            title('原始图像','Parent', ax1);

            function filterImage(len,wid, dropdown, ax1, ax2)
                % 获取Size值
                lenValue = len.Value;
                widValue = wid.Value;

                % 获取Padding pattern
                operation = dropdown.Value;

               SE = strel("rectangle",[lenValue widValue]);

               switch operation
                   case 'erode'
                       morphedImg = imerode(img, SE );
                   case 'dilate'
                       morphedImg = imdilate(img, SE );
                   case 'open'
                       morphedImg = imopen(img, SE );
                   case 'close'
                       morphedImg = imclose(img, SE );
               end

               % 显示滤波后的图像
               imshow(morphedImg, 'Parent', ax2);
               title(operation,'Parent', ax2);

                % 更新 current_img
                app.current_img = morphedImg;
                % 在进行操作后，将新的图像添加到栈中
                app.imgStack{end+1} = app.current_img;
            end
        end

        % Menu selected function: Menu_59
        function Menu_59Selected(app, event)
            % 孔洞填充
            % 记录图像
            app.last_img=app.current_img;
            % 创建figure
            fig = uifigure('Name', 'Hole filling','Position', [500, 300, 450, 300]);

            % 创建axes用于显示原始图像和滤波后的图像
            ax1 = uiaxes(fig, 'Position', [20 50 200 200]);
            ax2 = uiaxes(fig, 'Position', [230 50 200 200]);
            img=app.current_img;
            imshow(img,'Parent',ax1);title('原图','Parent',ax1);

            BW=imbinarize(img);
            img_fill = imfill(BW,'holes');
            
            imshow(img_fill,'Parent',ax2);
            title('孔洞填充','Parent',ax2);

            % 更新 current_img
            app.current_img = img_fill;
            % 在进行操作后，将新的图像添加到栈中
            app.imgStack{end+1} = app.current_img;
        end

        % Menu selected function: Menu_57
        function Menu_57Selected(app, event)
            % 边缘检测
            if isempty(app.img)
                uialert(app.UIFigure, '请先打开一个图像', '错误');
                return;
            else
            % 记录图像
            app.last_img=app.current_img;
            img = app.current_img;

            % 创建一个新窗口
            morphologyFig = uifigure('Name', '数学形态学处理', 'NumberTitle', 'off');
            set(morphologyFig, 'Position', [800, 500, 500, 300]); % 设置窗口位置和大小

            % 创建axes用于显示原始图像和滤波后的图像
            ax1 = uiaxes(morphologyFig, 'Position', [20 100 200 200]);
            ax2 = uiaxes(morphologyFig, 'Position', [250 100 200 200]);

            % 创建文本框和输入框
            uilabel(morphologyFig, 'Position', [50 50 100 20], 'Text', 'threshold=');
            threshold = uieditfield(morphologyFig, 'numeric', 'Position', [150 50 50 20],'Value',0.4);
            % % 创建文本框和输入框
            % uilabel(morphologyFig, 'Position', [120 50 50 20], 'Text', 'wid=');
            % wid = uieditfield(morphologyFig, 'numeric', 'Position', [170 50 50 20]);

            % 创建下拉菜单用于选择形态学操作
            uilabel(morphologyFig, 'Position', [200 50 100 20], 'Text', 'method:');
            dropdown = uidropdown(morphologyFig, 'Position', [300 50 80 20], 'Items', {'Sobel', 'Canny','Prewitt', 'Roberts'}, 'Value', 'Sobel');

            % 创建滤波按钮
            button = uibutton(morphologyFig, 'push', 'Position', [400 50 80 20], 'Text', '处理');
            button.ButtonPushedFcn = @(btn,event) filterImage(threshold, dropdown, ax1, ax2);

            % 加载并显示原始图像
            imshow(img, 'Parent', ax1);
            title('原始图像','Parent', ax1);
            end

            function filterImage(threshold, dropdown, ax1, ax2)
                % 获取Size值
                thresholdValue = threshold.Value;
                %widValue = wid.Value;

                % 获取Padding pattern
                method = dropdown.Value;

               img_edge = edge(img,method,thresholdValue);

               % 显示滤波后的图像
               imshow(img_edge, 'Parent', ax2);
               title(method,'Parent', ax2);

                % 更新 current_img
                app.current_img = img_edge;
                % 在进行操作后，将新的图像添加到栈中
                app.imgStack{end+1} = app.current_img;
            end
        end

        % Menu selected function: Menu_60
        function Menu_60Selected(app, event)
            % 检查是否为部署的应用程序
            if isdeployed
                % 获取CTF文件的根目录
                appRootDir = ctfroot;
                % 构建help.txt文件的完整路径
                helpFilePath = fullfile(appRootDir, 'for_redistribution_files_only', 'help.txt');
            else
                % 如果在MATLAB环境中，则使用相对路径或其他适当的路径
                helpFilePath = 'help.txt';
            end

            % 尝试打开文件
            try
                % 使用操作系统命令打开文件
                winopen(helpFilePath);
            catch exception
                % 如果文件无法打开，显示错误信息
                errMsg = sprintf('无法打开帮助文件。错误信息: %s', exception.message);
                uialert(app.UIFigure, errMsg, '错误');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = '文件';
            app.Menu_2 = uimenu(app.Menu);
            app.Menu_2.MenuSelectedFcn = createCallbackFcn(app, @Menu_2Selected, true);
            app.Menu_2.Text = '打开';
            app.Menu_5 = uimenu(app.Menu);
            app.Menu_5.MenuSelectedFcn = createCallbackFcn(app, @Menu_5Selected, true);
            app.Menu_5.Text = '恢复';
            app.Menu_22 = uimenu(app.Menu);
            app.Menu_22.MenuSelectedFcn = createCallbackFcn(app, @Menu_22Selected, true);
            app.Menu_22.Text = '显示';
            app.Menu_23 = uimenu(app.Menu);
            app.Menu_23.MenuSelectedFcn = createCallbackFcn(app, @Menu_23Selected, true);
            app.Menu_23.Text = '撤销';
            app.Menu_3 = uimenu(app.Menu);
            app.Menu_3.MenuSelectedFcn = createCallbackFcn(app, @Menu_3Selected, true);
            app.Menu_3.Text = '保存';
            app.Menu_4 = uimenu(app.Menu);
            app.Menu_4.MenuSelectedFcn = createCallbackFcn(app, @Menu_4Selected, true);
            app.Menu_4.Text = '关闭';
            app.Menu_6 = uimenu(app.UIFigure);
            app.Menu_6.MenuSelectedFcn = createCallbackFcn(app, @Menu_6Selected, true);
            app.Menu_6.Text = '灰度变换与空域滤波';
            app.Menu_7 = uimenu(app.Menu_6);
            app.Menu_7.MenuSelectedFcn = createCallbackFcn(app, @Menu_7Selected, true);
            app.Menu_7.Text = '基本灰度变换';
            app.Menu_8 = uimenu(app.Menu_7);
            app.Menu_8.MenuSelectedFcn = createCallbackFcn(app, @Menu_8Selected, true);
            app.Menu_8.Text = '图像反转';
            app.Menu_26 = uimenu(app.Menu_7);
            app.Menu_26.MenuSelectedFcn = createCallbackFcn(app, @Menu_26Selected, true);
            app.Menu_26.Text = '线性变换';
            app.Menu_9 = uimenu(app.Menu_7);
            app.Menu_9.MenuSelectedFcn = createCallbackFcn(app, @Menu_9Selected, true);
            app.Menu_9.Text = '对数变换';
            app.Menu_10 = uimenu(app.Menu_7);
            app.Menu_10.MenuSelectedFcn = createCallbackFcn(app, @Menu_10Selected, true);
            app.Menu_10.Text = '幂律变换';
            app.Menu_11 = uimenu(app.Menu_7);
            app.Menu_11.MenuSelectedFcn = createCallbackFcn(app, @Menu_11Selected, true);
            app.Menu_11.Text = '分段线性变换';
            app.Menu_24 = uimenu(app.Menu_7);
            app.Menu_24.MenuSelectedFcn = createCallbackFcn(app, @Menu_24Selected, true);
            app.Menu_24.Text = '非线性变换';
            app.Menu_12 = uimenu(app.Menu_6);
            app.Menu_12.Text = '直方图处理';
            app.Menu_13 = uimenu(app.Menu_12);
            app.Menu_13.MenuSelectedFcn = createCallbackFcn(app, @Menu_13Selected, true);
            app.Menu_13.Text = '直方图显示';
            app.Menu_14 = uimenu(app.Menu_12);
            app.Menu_14.MenuSelectedFcn = createCallbackFcn(app, @Menu_14Selected, true);
            app.Menu_14.Text = '直方图均衡化';
            app.Menu_15 = uimenu(app.Menu_12);
            app.Menu_15.MenuSelectedFcn = createCallbackFcn(app, @Menu_15Selected, true);
            app.Menu_15.Text = '直方图匹配';
            app.Menu_16 = uimenu(app.Menu_12);
            app.Menu_16.MenuSelectedFcn = createCallbackFcn(app, @Menu_16Selected, true);
            app.Menu_16.Text = '局部直方图处理';
            app.Menu_17 = uimenu(app.Menu_12);
            app.Menu_17.MenuSelectedFcn = createCallbackFcn(app, @Menu_17Selected, true);
            app.Menu_17.Text = '统计量增强';
            app.Menu_18 = uimenu(app.Menu_6);
            app.Menu_18.Text = '空间滤波';
            app.Menu_19 = uimenu(app.Menu_18);
            app.Menu_19.MenuSelectedFcn = createCallbackFcn(app, @Menu_19Selected, true);
            app.Menu_19.Text = '盒式滤波器';
            app.Menu_20 = uimenu(app.Menu_18);
            app.Menu_20.MenuSelectedFcn = createCallbackFcn(app, @Menu_20Selected, true);
            app.Menu_20.Text = '高斯滤波器';
            app.Menu_27 = uimenu(app.Menu_18);
            app.Menu_27.MenuSelectedFcn = createCallbackFcn(app, @Menu_27Selected, true);
            app.Menu_27.Text = '梯度算子';
            app.laplaceMenu = uimenu(app.Menu_18);
            app.laplaceMenu.MenuSelectedFcn = createCallbackFcn(app, @laplaceMenuSelected, true);
            app.laplaceMenu.Text = 'laplace算子';
            app.Menu_21 = uimenu(app.Menu_18);
            app.Menu_21.MenuSelectedFcn = createCallbackFcn(app, @Menu_21Selected, true);
            app.Menu_21.Text = '双边滤波器';
            app.Menu_33 = uimenu(app.Menu_18);
            app.Menu_33.MenuSelectedFcn = createCallbackFcn(app, @Menu_33Selected, true);
            app.Menu_33.Text = '最大值滤波';
            app.Menu_34 = uimenu(app.Menu_18);
            app.Menu_34.MenuSelectedFcn = createCallbackFcn(app, @Menu_34Selected, true);
            app.Menu_34.Text = '最小值滤波';
            app.Menu_35 = uimenu(app.Menu_18);
            app.Menu_35.MenuSelectedFcn = createCallbackFcn(app, @Menu_35Selected, true);
            app.Menu_35.Text = '中值滤波';
            app.Menu_36 = uimenu(app.Menu_18);
            app.Menu_36.MenuSelectedFcn = createCallbackFcn(app, @Menu_36Selected, true);
            app.Menu_36.Text = '几何均值滤波';
            app.Menu_37 = uimenu(app.Menu_18);
            app.Menu_37.MenuSelectedFcn = createCallbackFcn(app, @Menu_37Selected, true);
            app.Menu_37.Text = '谐波均值滤波';
            app.Menu_38 = uimenu(app.Menu_18);
            app.Menu_38.MenuSelectedFcn = createCallbackFcn(app, @Menu_38Selected, true);
            app.Menu_38.Text = '逆谐波均值滤波';
            app.Menu_25 = uimenu(app.Menu_6);
            app.Menu_25.MenuSelectedFcn = createCallbackFcn(app, @Menu_25Selected, true);
            app.Menu_25.Text = '比特平面切割';
            app.Menu_28 = uimenu(app.Menu_6);
            app.Menu_28.Text = '几何变换';
            app.Menu_29 = uimenu(app.Menu_28);
            app.Menu_29.MenuSelectedFcn = createCallbackFcn(app, @Menu_29Selected, true);
            app.Menu_29.Text = '平移';
            app.Menu_30 = uimenu(app.Menu_28);
            app.Menu_30.MenuSelectedFcn = createCallbackFcn(app, @Menu_30Selected, true);
            app.Menu_30.Text = '旋转';
            app.Menu_31 = uimenu(app.Menu_28);
            app.Menu_31.MenuSelectedFcn = createCallbackFcn(app, @Menu_31Selected, true);
            app.Menu_31.Text = '镜像';
            app.Menu_32 = uimenu(app.Menu_28);
            app.Menu_32.MenuSelectedFcn = createCallbackFcn(app, @Menu_32Selected, true);
            app.Menu_32.Text = '缩放';
            app.Menu_39 = uimenu(app.UIFigure);
            app.Menu_39.MenuSelectedFcn = createCallbackFcn(app, @Menu_39Selected, true);
            app.Menu_39.Text = '噪声与频域滤波';
            app.Menu_40 = uimenu(app.Menu_39);
            app.Menu_40.MenuSelectedFcn = createCallbackFcn(app, @Menu_40Selected, true);
            app.Menu_40.Text = '噪声处理';
            app.Menu_51 = uimenu(app.Menu_40);
            app.Menu_51.MenuSelectedFcn = createCallbackFcn(app, @Menu_51Selected, true);
            app.Menu_51.Text = '高斯噪声';
            app.Menu_52 = uimenu(app.Menu_40);
            app.Menu_52.MenuSelectedFcn = createCallbackFcn(app, @Menu_52Selected, true);
            app.Menu_52.Text = '泊松噪声';
            app.Menu_53 = uimenu(app.Menu_40);
            app.Menu_53.MenuSelectedFcn = createCallbackFcn(app, @Menu_53Selected, true);
            app.Menu_53.Text = '椒盐噪声';
            app.Menu_54 = uimenu(app.Menu_40);
            app.Menu_54.MenuSelectedFcn = createCallbackFcn(app, @Menu_54Selected, true);
            app.Menu_54.Text = '均匀噪声';
            app.Menu_41 = uimenu(app.Menu_39);
            app.Menu_41.MenuSelectedFcn = createCallbackFcn(app, @Menu_41Selected, true);
            app.Menu_41.Text = '频谱';
            app.Menu_42 = uimenu(app.Menu_39);
            app.Menu_42.MenuSelectedFcn = createCallbackFcn(app, @Menu_42Selected, true);
            app.Menu_42.Text = '大气湍流';
            app.Menu_43 = uimenu(app.Menu_39);
            app.Menu_43.MenuSelectedFcn = createCallbackFcn(app, @Menu_43Selected, true);
            app.Menu_43.Text = '运动模糊';
            app.Menu_44 = uimenu(app.Menu_39);
            app.Menu_44.Text = '滤波';
            app.Menu_45 = uimenu(app.Menu_44);
            app.Menu_45.MenuSelectedFcn = createCallbackFcn(app, @Menu_45Selected, true);
            app.Menu_45.Text = '低通滤波';
            app.Menu_46 = uimenu(app.Menu_44);
            app.Menu_46.MenuSelectedFcn = createCallbackFcn(app, @Menu_46Selected, true);
            app.Menu_46.Text = '高通滤波';
            app.Menu_47 = uimenu(app.Menu_44);
            app.Menu_47.MenuSelectedFcn = createCallbackFcn(app, @Menu_47Selected, true);
            app.Menu_47.Text = '同态滤波';
            app.Menu_48 = uimenu(app.Menu_44);
            app.Menu_48.MenuSelectedFcn = createCallbackFcn(app, @Menu_48Selected, true);
            app.Menu_48.Text = '陷波滤波器';
            app.Menu_49 = uimenu(app.Menu_44);
            app.Menu_49.MenuSelectedFcn = createCallbackFcn(app, @Menu_49Selected, true);
            app.Menu_49.Text = '逆滤波';
            app.Menu_50 = uimenu(app.Menu_44);
            app.Menu_50.MenuSelectedFcn = createCallbackFcn(app, @Menu_50Selected, true);
            app.Menu_50.Text = '维纳滤波';
            app.Menu_55 = uimenu(app.UIFigure);
            app.Menu_55.MenuSelectedFcn = createCallbackFcn(app, @Menu_55Selected, true);
            app.Menu_55.Text = '图像压缩';
            app.rmseMenu = uimenu(app.Menu_55);
            app.rmseMenu.MenuSelectedFcn = createCallbackFcn(app, @rmseMenuSelected, true);
            app.rmseMenu.Text = '计算rmse';
            app.huffmanMenu = uimenu(app.Menu_55);
            app.huffmanMenu.MenuSelectedFcn = createCallbackFcn(app, @huffmanMenuSelected, true);
            app.huffmanMenu.Text = 'huffman';
            app.Menu_56 = uimenu(app.UIFigure);
            app.Menu_56.MenuSelectedFcn = createCallbackFcn(app, @Menu_56Selected, true);
            app.Menu_56.Text = '数学形态学';
            app.Menu_58 = uimenu(app.Menu_56);
            app.Menu_58.MenuSelectedFcn = createCallbackFcn(app, @Menu_58Selected, true);
            app.Menu_58.Text = '形态学处理';
            app.Menu_59 = uimenu(app.Menu_56);
            app.Menu_59.MenuSelectedFcn = createCallbackFcn(app, @Menu_59Selected, true);
            app.Menu_59.Text = '孔洞填充';
            app.Menu_57 = uimenu(app.UIFigure);
            app.Menu_57.MenuSelectedFcn = createCallbackFcn(app, @Menu_57Selected, true);
            app.Menu_57.Text = '边缘检测';
            app.Menu_60 = uimenu(app.UIFigure);
            app.Menu_60.MenuSelectedFcn = createCallbackFcn(app, @Menu_60Selected, true);
            app.Menu_60.Text = '帮助';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end